classdef StatsView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        StatisticalTestPanel         matlab.ui.container.Panel
        GridLayout11                 matlab.ui.container.GridLayout
        mixedReason                  matlab.ui.control.Label
        betweenReason                matlab.ui.control.Label
        withinReason                 matlab.ui.control.Label
        mixedTest                    matlab.ui.control.Label
        betweenTest                  matlab.ui.control.Label
        withinTest                   matlab.ui.control.Label
        doMixed                      matlab.ui.control.CheckBox
        doBetween                    matlab.ui.control.CheckBox
        doWithin                     matlab.ui.control.CheckBox
        Panel_2                      matlab.ui.container.Panel
        GridLayout9                  matlab.ui.container.GridLayout
        SeparateBySubject            matlab.ui.control.CheckBox
        BetweenVariableLabel         matlab.ui.control.Label
        WithinVariableLabel          matlab.ui.control.Label
        StratifiedVariable           matlab.ui.control.DropDown
        doStratified                 matlab.ui.control.CheckBox
        SelectedLevelsLabel          matlab.ui.control.Label
        BetweenVariable              matlab.ui.control.DropDown
        CovariatesTree               matlab.ui.container.CheckBoxTree
        ConsiderCoovariates          matlab.ui.control.CheckBox
        CaseLabel                    matlab.ui.control.Label
        CompareAgainst               matlab.ui.control.DropDown
        doCompare                    matlab.ui.control.CheckBox
        Case                         matlab.ui.control.DropDown
        FilterBy                     matlab.ui.control.DropDown
        FilterbyVariable             matlab.ui.control.CheckBox
        SelectedLevelsTree           matlab.ui.container.CheckBoxTree
        SelectedLevels               matlab.ui.container.TreeNode
        WithinVariable               matlab.ui.control.DropDown
        ResponseVariable             matlab.ui.control.DropDown
        MetricLabel                  matlab.ui.control.Label
        Panel                        matlab.ui.container.Panel
        GridLayout8                  matlab.ui.container.GridLayout
        CopyFiguretoClipboardButton  matlab.ui.control.Button
        EditVariableTypesButton      matlab.ui.control.Button
        BuilddatasetButton           matlab.ui.control.Button
        LoaddatasetButton            matlab.ui.control.Button
        ViewBoxchartandStatsButton   matlab.ui.control.Button
        ExportPlotandStatsButton     matlab.ui.control.Button
        PlotOptionsPanel             matlab.ui.container.Panel
        GridLayout7                  matlab.ui.container.GridLayout
        Splitbetweenclasses          matlab.ui.control.CheckBox
        viewMixed                    matlab.ui.control.CheckBox
        viewBetween                  matlab.ui.control.CheckBox
        viewWithin                   matlab.ui.control.CheckBox
        FontLine                     matlab.ui.control.DropDown
        FontSizeandLineWeightsDropDownLabel  matlab.ui.control.Label
        Boxplot                      matlab.ui.control.CheckBox
        Violin                       matlab.ui.control.CheckBox
        Median                       matlab.ui.control.CheckBox
        Mean                         matlab.ui.control.CheckBox
        PlottypeLabel                matlab.ui.control.Label
        TrendLinesLabel              matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        Plots                        matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        BetweenTab                   matlab.ui.container.Tab
        GridLayout6_2                matlab.ui.container.GridLayout
        betweenTable                 matlab.ui.control.Table
        BetweenWithinTab             matlab.ui.container.Tab
        GridLayout6                  matlab.ui.container.GridLayout
        mixedTable                   matlab.ui.control.Table
        WithinTab                    matlab.ui.container.Tab
        GridLayout6_3                matlab.ui.container.GridLayout
        withinTable                  matlab.ui.control.Table
        StratifiedTab                matlab.ui.container.Tab
        GridLayout6_4                matlab.ui.container.GridLayout
        stratifiedTable              matlab.ui.control.Table
        EditVarsPanel                matlab.ui.container.Panel
        GridLayout10                 matlab.ui.container.GridLayout
        CancelEdits                  matlab.ui.control.Button
        OKButton                     matlab.ui.control.Button
        EditVarsTable                matlab.ui.control.Table
        SetpathsPanel                matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        CancelButton                 matlab.ui.control.Button
        Use_clusters                 matlab.ui.control.CheckBox
        Clusters_ChoosefileButton    matlab.ui.control.Button
        clusters_fileField           matlab.ui.control.EditField
        ClusteringDataLabel          matlab.ui.control.Label
        Use_sociodemo                matlab.ui.control.CheckBox
        Use_psycho                   matlab.ui.control.CheckBox
        Use_anns                     matlab.ui.control.CheckBox
        Use_physio                   matlab.ui.control.CheckBox
        BuilddatasetButton_2         matlab.ui.control.Button
        Sociodemo_ChoosefileButton   matlab.ui.control.Button
        Psycho_ChoosefileButton      matlab.ui.control.Button
        Physio_SelectpathButton      matlab.ui.control.Button
        sociodemo_fileField          matlab.ui.control.EditField
        SociodemographicalDataEditFieldLabel  matlab.ui.control.Label
        annotations_pathField        matlab.ui.control.EditField
        PhysiologicalAnnotationsEditFieldLabel  matlab.ui.control.Label
        psycho_fileField             matlab.ui.control.EditField
        PsychologicalDataEditFieldLabel  matlab.ui.control.Label
        Physio_ChoosefileButton      matlab.ui.control.Button
        physio_fileField             matlab.ui.control.EditField
        PhysiologicalIndexesEditFieldLabel  matlab.ui.control.Label
        ContextMenu                  matlab.ui.container.ContextMenu
        SelectAllMenu                matlab.ui.container.Menu
        DeselectAllMenu              matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

%% PROPERTIES

    properties (Access = public)

        Indexes             = table();% Description
        Variables           = {};

        WithinVars          = {};
        BetweenVars         = {};
        ResponseVars        = {};
        CovariateVars       = {};
        Conditions          = {};

        responseVar         = "";
        betweenVar          = "";
        withinVar           = "";
        stratifiedVar       = "";
        covariateVar        = {};
        selectedConditions  = [];

        view_between        = true;
        view_within         = true;
        view_mixed          = true;
        do_between          = false;
        do_covariate        = false;
        do_within           = true;
        do_mixed            = false;
        do_stratified       = false;
        do_filterBy         = false;
        split_between       = false;
        do_bySubject        = true;

        filename_physio     = "/Users/chiaramaninetti/Library/CloudStorage/OneDrive-PolitecnicodiMilano/PhD/MUSA/python/AE/Indexes.mat";
        filename_sociodemo  = '/Users/chiaramaninetti/Library/CloudStorage/OneDrive-SharedLibraries-PolitecnicodiMilano/expEIA - 4P Data Exchange - 4P Data Exchange/MED4PED/FISIO/SocioDemo.csv';
        filename_psycho     = '/Users/chiaramaninetti/Library/CloudStorage/OneDrive-PolitecnicodiMilano/PhD/MED4PED/Database PRIN questionari psico.xlsx';
        filename_clusters   = "/Users/chiaramaninetti/Library/CloudStorage/OneDrive-PolitecnicodiMilano/PhD/MUSA/python/AE/clusters_test_model.csv";
        annotations_path    = '/Users/chiaramaninetti/Library/CloudStorage/OneDrive-SharedLibraries-PolitecnicodiMilano/expEIA - 4P Data Exchange - 4P Data Exchange/MED4PED/FISIO/INDEXES_PHYSIO/Annotations';

        caseDiff            = false;
        isAngle             = false;
        TLayout             = [];
        hMLEHeatmap

        T                   = table();
        p_tables            = struct('between', table(), ...
                                     'within', table(), ...
                                     'mixed', table(), ...
                                     'stratified', table());
        p_values            = struct('between', nan, ...
                                     'within', nan, ...
                                     'mixed', nan, ...
                                     'stratified', nan);

        stats_ok            = false;
        anyChanging         = struct('betweenVar', false, ...
                                     'withinVar', false, ...
                                     'responseVar', false, ...
                                     'stratifiedVar', false);

    end

%% METHODS

    methods (Access = private)

        %READDATASET Initialize variables and UI state from the loaded
        %dataset.
        %
        %   Initialize variables and UI state from the loaded dataset.
        function readDataset(app)

            app.BetweenVars = {};
            app.WithinVars  = {};
            app.ResponseVars = {};
            app.CovariateVars = {};
            app.Indexes = removevars(app.Indexes, varfun(@(x) all(ismissing(x)), app.Indexes, "OutputFormat", "uniform"));
            for prop_ = {'IsWithin', 'IsBetween', 'IsCovariate', 'IsResponse'}
                prop = prop_{:};
                if ~isprop(app.Indexes, prop)
                    app.Indexes = addprop(app.Indexes, prop, 'variable');
                else
                    app.(erase(prop, "Is") + "Vars") = app.Indexes.Properties.VariableNames(app.Indexes.Properties.CustomProperties.(prop));
                end
            end
            app.Variables = app.Indexes.Properties.VariableNames;

            if isempty(app.ResponseVars)
                app.ResponseVars = app.Indexes.Properties.VariableNames(varfun(@isnumeric, app.Indexes, "OutputFormat", "uniform"));
            end
            if isempty(app.BetweenVars) && isempty(app.WithinVars)
                catVarNames = setdiff([string(app.Indexes.Properties.VariableNames(app.Indexes.Properties.VariableTypes == "categorical"))], "ID");
                IDs = categories(app.Indexes.ID);
                for v = 1:numel(catVarNames)
                    var = catVarNames{v};
                    nCatsPerID = zeros(numel(IDs),1);
                    for i = 1:numel(IDs)
                        idx = app.Indexes.ID == IDs{i};
                        nCatsPerID(i) = numel(categories(removecats(app.Indexes{idx,var})));
                    end
                    if all(nCatsPerID <= 1)
                        app.BetweenVars{end+1} = var;
                    else
                        app.WithinVars{end+1} = var;
                    end
                end
            end

            if isempty(app.CovariateVars)
                app.CovariateVars = string([app.BetweenVars(:)', app.WithinVars(:)']);
            end

            app.ResponseVariable.Items = sort(app.ResponseVars);
            app.ResponseVariable.Value = app.ResponseVariable.Items(1);
            app.responseVar            = app.ResponseVariable.Value;
            for temp = ["Within", "Between", "Covariate", "Response"]
                app.(temp + "Vars")(app.(temp + "Vars") == "ID") = [];
            end

            updateVariableProps(app)
            updateCovariateTree(app)
            app.BetweenVariable.Items = app.BetweenVars;

            if ~isempty(app.BetweenVariable.Items)
                app.BetweenVariable.Value = app.BetweenVariable.Items(1);
                app.betweenVar = char(app.BetweenVariable.Items(1));
                if strcmp(app.betweenVar, 'None')
                    app.do_between = false;
                    app.view_between = false;
                    app.doBetween.Value = false;
                    app.viewBetween.Value = false;
                else
                    app.do_between = true;
                    app.view_between = true;
                    app.doBetween.Value = true;
                    app.viewBetween.Value = true;
                    for betweenVar_temp = string(app.BetweenVariable.Items(:)')
                        if strcmp(betweenVar_temp, 'None')
                            continue
                        end
                    end
                end
            else
                app.betweenVar = 'None';
                app.do_between = false;
                app.view_between = false;
                app.doBetween.Value = false;
                app.viewBetween.Value = false;
            end

            app.WithinVariable.Items = app.WithinVars;
            if ~isempty(app.WithinVariable.Items)
                tmp = setdiff(app.WithinVariable.Items, app.betweenVar);
                app.WithinVariable.Value = tmp(1);
                app.withinVar = tmp(1);
            else
                app.withinVar = "";
            end

            parseConditions(app)
            WithinVariableChanged(app)

        end

        %UPDATESTATSTABLE Render p-value tables in the UI with significance
        %highlighting.
        %
        %   Render p-value tables in the UI with significance highlighting.
        function updateStatsTable(app)
            s_p  = uistyle("BackgroundColor", [229 250 221]/256);
            s_raw = uistyle("BackgroundColor", [250, 255, 210]/256);
            for table_temp = ["between", "within", "mixed", "stratified"]
                removeStyle(app.(table_temp + "Table"));
                columnNames = app.p_tables.(table_temp).Properties.VariableNames;
                for var_temp = ["between", "within"]
                    var = app.(var_temp + "Var");
                    for columnName = var_temp + "Condition" + ["", "1", "2"]
                        if ismember(columnName, columnNames)
                            app.p_tables.(table_temp).(columnName) = categorical(app.p_tables.(table_temp).(columnName), app.Conditions.(var));
                        end
                    end
                end
                app.(table_temp + "Table").ColumnName = app.p_tables.(table_temp).Properties.VariableNames;
                app.(table_temp + "Table").Data = app.p_tables.(table_temp);
                if height(app.p_tables.(table_temp))
                    try
                        [rows_raw, ~] = find(app.p_tables.(table_temp).pValue_raw < 0.05);
                        if ~isempty(rows_raw)
                            addStyle(app.(table_temp + "Table"), s_raw, "row", rows_raw);
                        end
                    catch 
                        
                    end
                    [rows, ~] = find(app.p_tables.(table_temp).pValue < 0.05);
                    if ~isempty(rows)
                        addStyle(app.(table_temp + "Table"), s_p, "row", rows);
                    end
                end
            end

        end

        %RESETVARS Reset internal state variables and statistics
        %containers.
        %
        %   Reset internal state variables and statistics containers.
        function resetVars(app)
            app.WithinVars          = {};
            app.BetweenVars         = {};
            app.ResponseVars        = {};
            app.CovariateVars       = {};
            app.Conditions          = {};
            app.Variables           = {};
            app.covariateVar        = "";
            app.stratifiedVar       = "";
            app.withinVar           = "";
            app.betweenVar          = "";
            app.responseVar         = "";

            app.Indexes             = table();

            app.caseDiff            = false;
            app.isAngle             = false;

            app.T                   = table();
            app.p_tables            = struct('between', table(), ...
                                             'within', table(), ...
                                             'mixed', table(), ...
                                             'stratified', table());
            app.p_values            = struct('between', nan, ...
                                             'within', nan, ...
                                             'mixed', nan, ...
                                             'stratified', nan);

            app.view_between        = true;
            app.view_within         = true;
            app.view_mixed          = true;
            app.do_between          = false;
            app.do_covariate        = false;
            app.do_within           = true;
            app.do_mixed            = false;
            app.do_stratified       = false;
            app.do_filterBy         = false;
            app.split_between       = false;
            app.do_bySubject        = true;

            app.TLayout             = [];
            app.selectedConditions  = [];
            app.stats_ok            = false;

        end

        %RESETFIELDS Reset file and path fields to defaults.
        %
        %   Reset file and path fields to defaults.
        function resetFields(app)
            app.sociodemo_fileField.Value   = app.filename_sociodemo;
            app.annotations_pathField.Value = app.annotations_path;
            app.psycho_fileField.Value      = app.filename_psycho;
            app.physio_fileField.Value      = app.filename_physio;
            app.clusters_fileField.Value    = app.filename_clusters;
        end

        %RESETCOMPONENTS Reset UI components to default selections and
        %values.
        %
        %   Reset UI components to default selections and values.
        function resetComponents(app)

            app.viewBetween.Value                   = app.view_between;
            app.viewWithin.Value                    = app.view_within;
            app.doStratified.Value                  = app.do_stratified;
            app.doBetween.Value                     = app.do_between;
            app.doWithin.Value                      = app.do_within;
            app.doMixed.Value                       = app.do_mixed;
            app.doCompare.Value                     = false;
            app.SeparateBySubject.Value             = true;
            app.FilterbyVariable.Value              = app.do_filterBy;
            delete(app.CovariatesTree.Children(:));
            delete(app.SelectedLevels.Children(:));
            app.WithinVariable.Items                = {};
            app.BetweenVariable.Items               = {};
            app.ResponseVariable.Items              = {};
            app.FilterBy.Items                      = {};
            app.Case.Items                          = {};
            app.CompareAgainst.Items                = {};
            app.StratifiedVariable.Items            = {};
            app.Violin.Value                        = false;
            app.Boxplot.Value                       = true;
            app.Mean.Value                          = false;
            app.Median.Value                        = true;
        end

        %PARSECONDITIONS Compute available condition levels for categorical
        %variables.
        %
        %   Compute available condition levels for categorical variables.
        function parseConditions(app)
            for var = setdiff(app.Variables, app.ResponseVars)
                app.Conditions.(string(var)) = string(rmmissing(unique(app.Indexes.(string(var)))));
            end
        end

        %STATISTICALTESTS Build analysis table and compute statistical
        %tests.
        %
        %   Build analysis table and compute statistical tests.
        function statisticalTests(app)


            try
                app.stats_ok = false;
                app.p_tables = struct('between', table(), ...
                                      'within', table(), ...
                                      'mixed', table(), ...
                                      'stratified', table());
                app.p_values = struct('between', nan, ...
                                      'within', nan, ...
                                      'mixed', nan, ...
                                      'stratified', nan);

                withinConditions_temp = intersect(string(app.Conditions.(app.withinVar)), {app.SelectedLevelsTree.CheckedNodes(:).Text});
                caseVar_temp = app.FilterBy.Value;
                if app.FilterbyVariable.Value
                    caseCondition = app.Case.Value;
                else
                    caseCondition = app.Conditions.(caseVar_temp);
                end
                caseConditions_temp = app.Conditions.(caseVar_temp);

                if app.do_covariate && ~isempty(app.CovariatesTree.CheckedNodes)
                    app.covariateVar = {app.CovariatesTree.CheckedNodes(:).Text};
                else
                    app.covariateVar = [];
                end

                idxs = ismember(app.Indexes.(app.withinVar), withinConditions_temp);
                vars = {'ID', app.withinVar, app.responseVar};
                if app.do_between || app.do_mixed
                    vars = [vars, app.betweenVar];
                end
                if app.do_filterBy
                    vars = [vars, caseVar_temp];
                    idxs = idxs & ismember(app.Indexes.(caseVar_temp), caseCondition);
                end
                if ~isempty(app.covariateVar)
                    vars = [vars, app.covariateVar];
                end
                if app.do_stratified
                    vars = [vars, app.stratifiedVar];
                end
                vars = rmmissing(unique(vars));
                app.T = rmmissing(app.Indexes(idxs, vars));

                if iscategorical(app.T.(app.responseVar))
                    app.T.(app.responseVar) = findgroups(app.T.(app.responseVar));
                end

                for v = string(vars)
                    if iscategorical(app.T.(v))
                        app.T.(v) = removecats(app.T.(v));
                    end
                end

                app.T = unique(app.T);
                if app.caseDiff
                    f = @(x, y) (y-x)/x * 100;
                    app.T = rmmissing(removevars(unstack(app.T, app.responseVar, caseVar_temp, "VariableNamingRule", "preserve", 'aggregationfunction', @median), setdiff(string(unique(app.T.(caseVar_temp))),  [{app.Case.Value}, {app.CompareAgainst.Value}])));
                    vars_temp = [arrayfun(@char, caseConditions_temp, 'UniformOutput', false)', {char(app.responseVar)}];
                    if ~contains(app.responseVar, "beta")
                        app.isAngle = false;
                        app.T = horzcat(removevars(app.T, intersect(app.T.Properties.VariableNames, vars_temp)), rowfun(f, app.T, 'InputVariables', intersect(char(caseConditions_temp), app.T.Properties.VariableNames)', 'OutputVariableNames', app.responseVar));
                    else
                        app.isAngle = true;
                        fAngle = @(x, y) wrapToPi(y-x);
                        app.T = horzcat(removevars(app.T, intersect(app.T.Properties.VariableNames, vars_temp)), rowfun(fAngle, app.T, 'InputVariables', intersect(char(caseConditions_temp), app.T.Properties.VariableNames), 'OutputVariableNames', app.responseVar));
                    end
                    app.T.(app.responseVar)(abs(app.T.(app.responseVar)) == Inf) = nan;
                elseif app.do_filterBy
                    app.T = removevars(app.T(ismember(app.T.(caseVar_temp), caseCondition), :), caseVar_temp);
                end

                choice = chooseTest(app);
                app.T = renamevars(app.T, app.responseVar, 'Y');

                if app.do_between
                    app.betweenTest.Text    = choice.between.name;
                    app.betweenReason.Text  = choice.between.reason;
                    computeBetween(app, choice.between.name)
                else
                    app.betweenTest.Text    = '';
                    app.betweenReason.Text  = '';
                end
                if app.do_within
                    app.withinTest.Text     = choice.within.name;
                    app.withinReason.Text   = choice.within.reason;
                    computeWithin(app, choice.within.name)
                else
                    app.withinTest.Text     = '';
                    app.withinReason.Text   = '';
                end
                if app.do_mixed
                    app.mixedTest.Text      = choice.mixed.name;
                    app.mixedReason.Text    = choice.mixed.reason;
                    computeMixed(app, choice.mixed.name)
                else
                    app.mixedTest.Text      = '';
                    app.mixedReason.Text    = '';
                end


                app.T = renamevars(app.T, 'Y', app.responseVar);

            catch ME
                disp(getReport(ME))
                % keyboard
            end
            updateStatsTable(app)
            app.stats_ok = true;

        end

        %COMPUTEBETWEEN Compute between-subject tests and p-value table.
        %
        %   Compute between-subject tests and p-value table.
        function computeBetween(app, test)
            tbl = table();
            T_temp = rmmissing(app.T);
            [g, g_names] = findgroups(T_temp.(app.betweenVar));
            switch test
                case 'ranksum'
                    [p, ~, stats] = ranksum(T_temp.Y(g == 1), T_temp.Y(g == 2));
                case 'ttest2'
                    [~, p, ~, stats] = ttest2(T_temp.Y(g == 1), T_temp.Y(g == 2));
                case 'anova'
                    [p, tbl, stats] = anova1(T_temp.Y, g, 'off');
                case 'kruskalwallis'
                    [p, tbl, stats] = kruskalwallis(T_temp.Y, g, 'off');
                case 'lm_or_ancova'
                    [p, tbl] = computeLmBetween(app, T_temp, g_names);
            end
            if isempty(tbl)
                tbl.betweenCondition1 = g_names(1);
                tbl.betweenCondition2 = g_names(2);
                tbl.pValue = p;
            else
                tbl = array2table(multcompare(stats, 'display', 'off', 'CriticalValueType', 'dunn-sidak'), ...
                    'VariableNames', {'betweenCondition1', 'betweenCondition2', 'lowerLim', 'diff', 'upperLim', 'pValue'});
                tbl.betweenCondition1 = g_names(tbl.betweenCondition1);
                tbl.betweenCondition2 = g_names(tbl.betweenCondition2);
            end
            app.p_values.between = p;
            app.p_tables.between = sortrows(tbl, {'betweenCondition1', 'betweenCondition2'});
        end

        %COMPUTEWITHIN Compute within-subject tests and p-value table.
        %
        %   Compute within-subject tests and p-value table.
        function computeWithin(app, test)
            tbl = table();
            T_temp = rmmissing(app.T, "DataVariables", 'Y');
            W = unstack(T_temp, 'Y', app.withinVar, 'VariableNamingRule', 'preserve', 'aggregationfunction', @median);
            withinLevels_short = intersect(app.Conditions.(app.withinVar), W.Properties.VariableNames);
            withinLevels = strrep(app.withinVar + string(withinLevels_short), '-', '_');
            W = renamevars(W, withinLevels_short, withinLevels);
            switch test
                case 'ttest_paired' % "Two paired conditions, normality not rejected."
                    [~, p, ~, ~] = ttest(W.(withinLevels{1}), W.(withinLevels{2}));
                case 'signrank'     % "Two paired conditions, non-normal."
                    [p, ~, ~] = signrank(W.(withinLevels{1}), W.(withinLevels{2}));
                case 'rm_anova'     % "Repeated measures (>=3 levels), normality not rejected."
                    [p, tbl] = computeRmAnovaWithin(app, W, withinLevels);
                case 'friedman'     % "Repeated measures (>=3 levels), non-normal."
                    [p, tbl] = computeFriedman(app, W, withinLevels);
                case 'lme'
                    [p, tbl] = computeLmeWithin(app, T_temp);
            end

            if isempty(tbl)
                tbl.withinCondition1 = withinLevels_short(1);
                tbl.withinCondition2 = withinLevels_short(2);
                tbl.pValue = p;
            end

            app.p_values.within = p;
            app.p_tables.within = sortrows(tbl, {'withinCondition1', 'withinCondition2'});

        end

        %COMPUTEMIXED Compute mixed-design tests and stratified p-values.
        %
        %   Compute mixed-design tests and stratified p-values.
        function computeMixed(app, test)
            T_temp = rmmissing(app.T, "DataVariables", 'Y');
            switch test
                case 'rm_anova_mixed'%
                    [p, tbl_strat, tbl_mixed] = computeRmAnovaMixed(app, T_temp);
                case {'lme_or_rank_based', 'lme'}
                    [p, tbl_strat, tbl_mixed] = computeMixedLME(app, T_temp);
            end
            app.p_values.mixed = p;
            app.p_tables.mixed = sortrows(tbl_mixed, {'betweenCondition', 'withinCondition1', 'withinCondition2'});
            app.p_tables.stratified = sortrows(tbl_strat, {'withinCondition', 'betweenCondition1', 'betweenCondition2'});
        end

        %P Helper function for app behavior.
        %
        %   Helper function for app behavior.
        function [p, tbl] = computeLmBetween(app, T_temp, g_names)
            tbl = table();
            if ~isempty(app.covariateVar)
                formula = "Y ~ " + strjoin(unique([app.betweenVar, cellstr(app.covariateVar)]), ' + ');
            else
                formula = "Y ~ " + app.betweenVar;
            end
            mdl = fitlm(T_temp, formula);
            a = anova(mdl);
            p = a(app.betweenVar, 'pValue').Variables;
            if numel(g_names) > 2
                [L, labels] = contrastsLM(app, mdl, app.betweenVar);
                p = nan(numel(labels), 1);
                for i = 1:numel(labels)
                    p(i) = coefTest(mdl, L(i, :));
                end
                % Multiplicity correction (BH-FDR; replace with Holm if you
                % prefer)
                q = mafdr(p, 'BHFDR', true);
                tbl = table(labels(:, 1), labels(:, 2), q, p, 'VariableNames', {'betweenCondition1', 'betweenCondition2', 'pValue', 'pValue_raw'});
            end
        end

        %P Helper function for app behavior.
        %
        %   Helper function for app behavior.
        function [p, tbl] = computeFriedman(app, W, withinLevels)
            [p, ~, stats] = friedman(rmmissing(W(:, (string(withinLevels))).Variables), 1, 'off');
            tbl = array2table(multcompare(stats, 'display', 'off', 'CriticalValueType', 'dunn-sidak'), ...
                'VariableNames', {'withinCondition1', 'withinCondition2', 'lowerLim', 'diff', 'upperLim', 'pValue'});
            withinLevels_short = erase(withinLevels, app.withinVar);
            tbl.withinCondition1 = withinLevels_short(tbl.withinCondition1);
            tbl.withinCondition2 = withinLevels_short(tbl.withinCondition2);
        end

        %P Helper function for app behavior.
        %
        %   Helper function for app behavior.
        function [p, tbl] = computeLmeWithin(app, T_temp)
            if isempty(app.covariateVar)
                covStr = "";
            else
                covStr = " + " + join(app.covariateVar, " + ");
            end
            try
                formula = "Y ~ 1 + " + app.withinVar + covStr + " + (1 + " + app.withinVar + " | ID)";
                lme = fitlme(T_temp, formula, 'FitMethod','REML');
            catch ME
                formula = "Y ~ 1 + " + app.withinVar + covStr + " + (1|ID)";
                lme = fitlme(T_temp, formula, 'FitMethod','REML');
            end
            p = dataset2table(anova(lme, "DFMethod", "satterthwaite"));
            p = p(strcmp(p.Term, app.withinVar), 'pValue').Variables;
            tbl = table();
            [L, labels] = contrastsLM(app, lme, app.withinVar);
            pp = nan(size(labels, 1), 1);
            for k = 1:size(L, 1)
                pp(k) = coefTest(lme, L(k, :));
            end
            tbl.withinCondition1 = labels(:, 1);
            tbl.withinCondition2 = labels(:, 2);
            qq = mafdr(pp, 'BHFDR', true);
            tbl.pValue = qq;
            tbl.pValue_raw = pp;
        end

        %P Helper function for app behavior.
        %
        %   Helper function for app behavior.
        function [p, tbl] = computeRmAnovaWithin(app, W, withinLevels)
            within = table(withinLevels, 'VariableNames', {app.withinVar});
            rm = fitrm(W, sprintf('%s-%s ~ 1', withinLevels{1}, withinLevels{end}), 'WithinDesign', within);
            if mauchly(rm).pValue < 0.05
                pVar = 'pValueGG'; % correct for non-sphericity
            else
                pVar = 'pValue';
            end
            ranovatbl = ranova(rm, 'WithinModel', char(app.withinVar));
            p = ranovatbl("(Intercept):" + app.withinVar, pVar).Variables;
            tbl = renamevars(multcompare(rm, app.withinVar), cellstr([app.withinVar + "_1", app.withinVar + "_2"]), {'withinCondition1', 'withinCondition2'});
            withinLevels_short = erase(withinLevels, app.withinVar);
            tbl.withinCondition1 = withinLevels_short(tbl.withinCondition1);
            tbl.withinCondition2 = withinLevels_short(tbl.withinCondition2);
        end

        %P Helper function for app behavior.
        %
        %   Helper function for app behavior.
        function [p, tbl_bw, tbl_strat] = computeMixedLME(app, T_temp)
            if isempty(app.covariateVar)
                covStr = "";
            else
                covStr = " + " + join(app.covariateVar, " + ");
            end

            try
                formula = "Y ~ 1 + " + app.betweenVar + " * " + app.withinVar + covStr + " + (1 + " + app.withinVar + " | ID)";
                lme = fitlme(T_temp, formula, 'FitMethod','REML');
            catch ME
                formula = "Y ~ 1 + " + app.betweenVar + " * " + app.withinVar + covStr + " + (1 | ID)";
                lme = fitlme(T_temp, formula, 'FitMethod','REML');
            end
            tbl = dataset2table(anova(lme, "DFMethod", "satterthwaite"));
            try
                p = tbl(strcmp(tbl.Term, app.betweenVar + ":" + app.withinVar), 'pValue').Variables;
            catch
                p = tbl(strcmp(tbl.Term, app.withinVar + ":" + app.betweenVar), 'pValue').Variables;
            end

            T_temp.ID = removecats(categorical(T_temp.ID));
            T_temp.(app.withinVar)  = removecats(categorical(T_temp.(app.withinVar)));
            T_temp.(app.betweenVar) = removecats(categorical(T_temp.(app.betweenVar)));

            formula   = "Y ~ 1 + " + app.betweenVar + covStr + " + (1 | ID)";
            tbl_bw    = stratify(app, T_temp, app.withinVar, app.betweenVar, formula);
            tbl_bw = renamevars(tbl_bw, {'Condition', 'Condition1', 'Condition2'}, {'withinCondition', 'betweenCondition1', 'betweenCondition2'});

            formula   = "Y ~ 1 + " + app.withinVar + covStr + " + (1 | ID)";
            tbl_strat = stratify(app, T_temp, app.betweenVar, app.withinVar, formula);
            tbl_strat = renamevars(tbl_strat, {'Condition', 'Condition1', 'Condition2'}, {'betweenCondition', 'withinCondition1', 'withinCondition2'});
        end

        %P Helper function for app behavior.
        %
        %   Helper function for app behavior.
        function [p, tbl_bw, tbl_strat] = computeRmAnovaMixed(app, T_temp)
            W = unstack(T_temp, 'Y', app.withinVar, 'VariableNamingRule', 'preserve', 'aggregationfunction', @median);
            withinLevels_short = intersect(app.Conditions.(app.withinVar), W.Properties.VariableNames);
            withinLevels = app.withinVar + string(withinLevels_short);
            W = renamevars(W, withinLevels_short, withinLevels);
            within = table(withinLevels, 'VariableNames', {app.withinVar});
            rm = fitrm(W, sprintf('%s-%s ~ %s', withinLevels{1}, withinLevels{end}, app.betweenVar), 'WithinDesign', within);
            if mauchly(rm).pValue < 0.05
                pVar = 'pValueGG';
            else
                pVar = 'pValue';
            end
            ranovatbl = ranova(rm, 'WithinModel', char(app.withinVar));
            try
                p = ranovatbl(app.betweenVar + ":" + app.withinVar, pVar);
            catch
                p = ranovatbl(app.withinVar + ":" + app.betweenVar, pVar);
            end
            tbl_bw = renamevars(multcompare(rm, app.withinVar, 'by', app.betweenVar, 'comparisontype', 'dunn-sidak'), cellstr([app.betweenVar, app.withinVar + "_1", app.withinVar + "_2"]), {'betweenCondition', 'withinCondition1', 'withinCondition2'});
            tbl_bw.withinCondition1 = extractAfter(tbl_bw.withinCondition1, app.withinVar);
            tbl_bw.withinCondition2 = extractAfter(tbl_bw.withinCondition2, app.withinVar);

            tbl_strat = renamevars(multcompare(rm, app.betweenVar, 'by', app.withinVar, 'comparisontype', 'dunn-sidak'), cellstr([app.withinVar, app.betweenVar + "_1", app.betweenVar + "_2"]), {'withinCondition', 'betweenCondition1', 'betweenCondition2'});
            tbl_strat.betweenCondition1 = extractAfter(tbl_strat.betweenCondition1, app.betweenVar);
            tbl_strat.betweenCondition2 = extractAfter(tbl_strat.betweenCondition2, app.betweenVar);
        end


        %TBL Helper function for app behavior.
        %
        %   Helper function for app behavior.
        function [tbl, p] = computeLME(app, T_temp, formula)

            try
                T_temp.(app.withinVar) = removecats(T_temp.(app.withinVar));
                lme = fitlme(T_temp, formula, 'FitMethod','REML');

                tbl = dataset2table(anova(lme));
                tbl.Properties.RowNames = tbl.Term;
                p = tbl(app.betweenVar + ":" + app.withinVar, 'pValue').Variables;
            catch
                p = table(nan);
            end

        end

        %UPDATECOVARIATETREE Populate the covariate selection tree.
        %
        %   Populate the covariate selection tree.
        function updateCovariateTree(app)
            delete(app.CovariatesTree.Children);
            for covariate = app.CovariateVars(:)'
                uitreenode(app.CovariatesTree, 'text', string(covariate));
            end
            app.CovariatesTree.CheckedNodes = [];
        end

        %UPDATEVARIABLEPROPS Sync variable roles
        %(between/within/response/covariate).
        %
        %   Sync variable roles (between/within/response/covariate).
        function updateVariableProps(app)
            for prop_ = {'IsWithin', 'IsBetween', 'IsCovariate', 'IsResponse'}
                prop = prop_{:};
                vars = ismember(app.Indexes.Properties.VariableNames, app.(erase(prop, "Is") + "Vars"));
                app.Indexes.Properties.CustomProperties.(prop) = vars;
            end
        end

        %GETVARIABLES Refresh variable lists and UI drop-downs.
        %
        %   Refresh variable lists and UI drop-downs.
        function getVariables(app)
            for v = ["Between", "Within", "Covariate", "Stratified"]
                if app.("do_" + lower(v))
                    switch v
                        case "Covariate"
                            if ~isempty(app.CovariatesTree.CheckedNodes)
                                app.covariateVar = [app.CovariatesTree.CheckedNodes(:).Text];
                            else
                                app.covariateVar = "";
                            end
                        otherwise
                            app.(lower(v) + "Var") = app.(v + "Variable").Value;
                    end
                else
                    app.(lower(v) + "Var") = "";
                end
            end
            app.responseVar = app.ResponseVariable.Value;
        end

        %ARECHANGESDONE Debounce updates and recompute stats/plots.
        %
        %   Debounce updates and recompute stats/plots.
        function areChangesDone(app)
            if ~(structfun(@any, app.anyChanging))
                statisticalTests(app)
                ViewPlots(app)
            end
        end

        %ISBALANCED Helper function for app behavior.
        %
        %   Helper function for app behavior.
        function isBalanced = checkBalanced(app, D)
            % Returns true only if every subject has all within × between
            % combinations exactly once (complete cells).

            if ~ismember('ID', D.Properties.VariableNames)
                error("checkBalanced: table must contain an 'ID' variable.");
            end
            if ~ismember(char(app.withinVar), D.Properties.VariableNames) || ~ismember(char(app.betweenVar), D.Properties.VariableNames)
                error("checkBalanced: table must contain withinVar and betweenVar.");
            end

            ids = unique(D.ID);
            nW = numel(categories(removecats(categorical(D.(char(app.withinVar))))));
            nB = numel(categories(removecats(categorical(D.(char(app.betweenVar))))));
            nExpected = nW * nB;

            isBalanced = true;

            for i = 1:numel(ids)
                Ti = D(D.ID == ids(i), :);
                g = findgroups(Ti.(char(app.withinVar)), Ti.(char(app.betweenVar)));
                if numel(unique(g)) ~= nExpected
                    isBalanced = false;
                    return
                end
                % Optional stricter check: ensure exactly one observation
                % per cell
                if any(splitapply(@numel, g, g) ~= 1)
                    isBalanced = false;
                    return
                end
            end
        end

        %CHOICE Helper function for app behavior.
        %
        %   Helper function for app behavior.
        function choice = chooseTest(app)
            % If both app.do_between and app.do_within are true, returns:
            %   choice.within, choice.between, choice.mixed
            % Otherwise returns only the relevant field(s).

            choice = struct();

            if app.do_within
                choice.within = chooseTest_one(app, "within");
            end

            if app.do_between
                choice.between = chooseTest_one(app, "between");
            end

            if app.do_mixed
                choice.mixed = chooseTest_one(app, "mixed");
            end
        end

        %OUT Helper function for app behavior.
        %
        %   Helper function for app behavior.
        function out = chooseTest_one(app, design)
            % design: "within" | "between" | "mixed"

            out = struct('name', "", 'family', "", 'design', design, 'reason', "", 'notes', "", 'assumptions', "");
            out.isBalanced = NaN;
            out.do_stratified = false;
            out.stratifiedVar = "";
            out.stratifiedRunner = "";
            out.stratifiedNotes = "";

            % ----------------------------- Pick the table that contains
            % variables -----------------------------
            if isprop(app, 'T') && istable(app.T)
                D = app.T;
            else
                D = app.Indexes;
            end

            yName = char(app.responseVar);
            y = D.(yName);

            % ----------------------------- Identify predictors for this
            % design -----------------------------
            X = string.empty(1, 0);

            if design == "between" || design == "mixed"
                X(end + 1) = string(app.betweenVar);
            end

            if design == "within" || design == "mixed"
                X(end + 1) = string(app.withinVar);
            end

            if app.do_covariate
                if isstring(app.covariateVar)
                    X = [X, app.covariateVar(:)'];
                else
                    X = [X, string(app.covariateVar(:)')];
                end
            end

            X = unique(X, 'stable');

            % ----------------------------- Outcome type
            % -----------------------------
            yIsCat = iscategorical(y) || isstring(y) || iscellstr(y);
            yIsNum = isnumeric(y) || islogical(y);

            yIsBinary = false;
            if yIsCat
                yy = removecats(categorical(y));
                yIsBinary = (numel(categories(yy)) == 2);
            elseif yIsNum
                uy = unique(double(y(~isnan(double(y)))));
                yIsBinary = (numel(uy) == 2) && all(ismember(uy, [0, 1]));
            end

            % ----------------------------- Levels
            % -----------------------------
            nBetweenLevels = NaN;
            nWithinLevels = NaN;

            if (design == "between" || design == "mixed")
                b = removecats(categorical(D.(char(app.betweenVar))));
                nBetweenLevels = numel(categories(b));
            end

            if (design == "within" || design == "mixed")
                w = removecats(categorical(D.(char(app.withinVar))));
                nWithinLevels = numel(categories(w));
            end

            % ----------------------------- Normality (continuous only)
            % -----------------------------
            normality = true;
            if ~yIsBinary && yIsNum
                try
                    normality = ~lillietest(y);
                catch
                    normality = false;
                end
            end

            % ----------------------------- Balance check (only meaningful
            % for mixed continuous; used to choose fitrm vs fitlme)
            % -----------------------------
            if design == "mixed" && yIsNum && ~yIsBinary
                try
                    out.isBalanced = checkBalanced(app, D);
                catch
                    out.isBalanced = NaN;
                end
            end
            % =========================================================
            % DECISIONS
            % =========================================================

            % ---------- Binary outcome ----------
            if yIsBinary
                out.family = "categorical (binary)";

                if app.do_covariate
                    if design == "within" || design == "mixed"
                        out.name = "glmm_binomial";
                        out.reason = "Binary outcome with repeated measures and/or multiple predictors.";
                        out.notes = "Use fitglme: Y ~ predictors + (1|ID), Distribution='binomial'.";
                        out.assumptions = "Random intercept for ID; consider random slopes for Within if supported.";
                    else
                        out.name = "glm_binomial";
                        out.reason = "Binary outcome with multiple predictors.";
                        out.notes = "Use fitglm: Distribution='binomial'.";
                        out.assumptions = "Independent observations.";
                    end
                else
                    if design == "between"
                        if ~isnan(nBetweenLevels) && nBetweenLevels == 2
                            out.name = "fisher_or_chi2_2x2";
                            out.reason = "Binary outcome, 2 independent groups.";
                            out.notes = "Use Fisher if expected counts are small; otherwise chi-square.";
                        else
                            out.name = "chi2_rx2";
                            out.reason = "Binary outcome, >2 independent groups.";
                            out.notes = "Chi-square test of independence (Rx2).";
                        end
                    elseif design == "within"
                        if ~isnan(nWithinLevels) && nWithinLevels == 2
                            out.name = "mcnemar";
                            out.reason = "Binary outcome, 2 paired conditions.";
                            out.notes = "Use McNemar; exact version if discordant counts are small.";
                        else
                            out.name = "cochran_q";
                            out.reason = "Binary outcome, >= 3 paired conditions.";
                            out.notes = "If significant, follow with pairwise McNemar + multiplicity correction.";
                        end
                    else
                        out.name = "glmm_binomial";
                        out.reason = "Binary outcome with within + between; need model to test main effects and interaction.";
                        out.notes = "Use fitglme: Y ~ Between * Within + (1|ID), Distribution='binomial'.";
                    end
                end

                % Stratified runner mapping (binary)
                if out.do_stratified
                    if ismember(out.name, ["glmm_binomial", "glm_binomial"])
                        out.stratifiedRunner = "fitglme_by_stratum";
                        out.stratifiedNotes = "Run the same GLM/GLMM within each level of stratifiedVar; correct across strata as needed.";
                    elseif ismember(out.name, ["mcnemar", "cochran_q"])
                        out.stratifiedRunner = "binary_nonparam_by_stratum";
                        out.stratifiedNotes = "Run McNemar/Cochran within each stratum; apply multiplicity correction across strata.";
                    else
                        out.stratifiedRunner = "binary_crosstab_by_stratum";
                        out.stratifiedNotes = "Run Fisher/Chi-square within each stratum; apply multiplicity correction across strata.";
                    end
                end

                return
            end

            % ---------- Continuous outcome ----------
            if yIsNum
                out.family = "continuous";

                if app.do_covariate
                    if design == "within" || design == "mixed"
                        out.name = "lme";
                        out.reason = "Continuous outcome with repeated measures and covariates.";
                        out.notes = "Use fitlme: Y ~ predictors + (1|ID) (and consider random slopes for Within).";
                    else
                        out.name = "lm_or_ancova";
                        out.reason = "Continuous outcome with covariates, independent observations.";
                        out.notes = "Use fitlm (or robust alternatives).";
                    end

                    % Stratified runner mapping (continuous + covariates)
                    if out.do_stratified
                        out.stratifiedRunner = "fitlme_by_stratum";
                        out.stratifiedNotes = "Run the same linear (mixed) model within each stratum; FDR across strata per term if desired.";
                    end

                    return
                end

                if design == "between"
                    if ~isnan(nBetweenLevels) && nBetweenLevels == 2
                        if normality
                            out.name = "ttest2";
                            out.reason = "Two independent groups, normality not rejected.";
                            out.notes = "Use Welch t-test if variances differ.";
                        else
                            out.name = "ranksum";
                            out.reason = "Two independent groups, non-normal.";
                            out.notes = "Mann-Whitney U (rank-sum).";
                        end
                    else
                        if normality
                            out.name = "anova1";
                            out.reason = ">2 independent groups, normality not rejected.";
                        else
                            out.name = "kruskalwallis";
                            out.reason = ">2 independent groups, non-normal.";
                        end
                    end

                    if out.do_stratified
                        out.stratifiedRunner = "continuous_test_by_stratum";
                        out.stratifiedNotes = "Run the selected between-subject test within each stratum; apply multiplicity correction across strata.";
                    end

                    return
                end

                if design == "within"
                    if ~isnan(nWithinLevels) && nWithinLevels == 2
                        if normality && (app.do_bySubject)  % <-- add guard
                            out.name = "ttest_paired";
                            out.reason = "Two paired conditions, normality not rejected.";
                            out.notes = "Use ttest (paired), not ttest2.";
                        elseif ~normality && (app.do_bySubject)  % <-- add guard
                            out.name = "signrank";
                            out.reason = "Two paired conditions, non-normal.";
                        else
                            out.name = "lme";
                            out.reason = "Two paired conditions, image-level pooled data; LME handles subject clustering.";
                            out.notes = "Use fitlme: Y ~ withinVar + (1|ID).";
                        end
                    else
                        if normality && app.do_bySubject  % <-- add guard
                            out.name = "rm_anova";
                            out.reason = "Repeated measures (>=3 levels), normality not rejected.";
                            out.notes = "Use fitrm/ranova; apply GG correction if sphericity fails.";
                        elseif ~normality && app.do_bySubject  % <-- add guard
                            out.name = "friedman";
                            out.reason = "Repeated measures (>=3 levels), non-normal.";
                        else
                            out.name = "lme";
                            out.reason = "Repeated measures (>=3 levels), image-level pooled data; LME handles subject clustering.";
                            out.notes = "Use fitlme: Y ~ withinVar + (1|ID).";
                        end
                    end 

                    if out.do_stratified
                        out.stratifiedRunner = "continuous_test_by_stratum";
                        out.stratifiedNotes = "Run the selected within-subject test within each stratum; apply multiplicity correction across strata.";
                    end

                    return
                end

                % mixed continuous
                if ~app.do_bySubject
                    out.name = "lme";
                    out.reason = "Mixed design, image-level pooled data; LME handles subject clustering via random effects.";
                    out.notes = "Use fitlme: Y ~ between * within + (1|ID); consider (1|ID:Batch) for nesting.";
                    out.assumptions = "Random intercept for ID required; check residuals.";
                elseif normality
                    if islogical(out.isBalanced) && out.isBalanced
                        out.name = "rm_anova_mixed";
                        out.reason = "Mixed design with complete balanced cells and normality not rejected.";
                        out.notes = "Use fitrm/ranova (sphericity check + GG correction as needed).";
                        out.assumptions = "Complete within × between cells per subject; sphericity (or corrected); approx normal residuals.";
                    else
                        out.name = "lme";
                        out.reason = "Mixed design with unbalanced/missing cells (or balance unknown); LME is appropriate.";
                        out.notes = "Use fitlme for missing/unbalanced data; consider random slopes for withinVar if stable.";
                        out.assumptions = "Model-based inference; check residuals; consider (1 + withinVar | ID) if supported.";
                    end
                else
                    out.name = "lme_or_rank_based";
                    out.reason = "Mixed design with non-normal outcome; classical nonparam tests cannot test interaction.";
                    out.notes = "Prefer fitlme with diagnostics/robustness, or rank-based factorial methods (outside base MATLAB).";
                    out.assumptions = "Model-based inference; consider transformation or robust SE if needed.";
                end

                % Stratified runner mapping (mixed continuous)
                if out.do_stratified
                    if ismember(out.name, ["lme", "rm_anova_mixed"])
                        out.stratifiedRunner = "fitlme_by_stratum";
                        out.stratifiedNotes = "Run the same formula within each stratum; extract anova(lme) p-values; FDR across strata per term.";
                    else
                        out.stratifiedRunner = "continuous_test_by_stratum";
                        out.stratifiedNotes = "Run the selected test within each stratum; apply multiplicity correction across strata.";
                    end
                end

                return
            end

            out.name = "unsupported";
            out.reason = "Outcome type not recognized as continuous or binary categorical.";


        end


        %TBL Helper function for app behavior.
        %
        %   Helper function for app behavior.
        function [tbl, p_by_stratum] = stratify(app, T_temp, strataVar, fixedVar, formula)

            strataLevels = categories(T_temp.(strataVar));

            models = cell(numel(strataLevels), 1);
            tbl = table();
            tbl.Condition   = strings(0, 1);
            tbl.Condition1 = strings(0, 1);
            tbl.Condition2 = strings(0, 1);
            % tbl.p = nan(0, 1);
            tbl.pValue = nan(0, 1); % within-stratum BH-FDR
            tbl.pValue_raw = nan(0, 1);

            p_by_stratum = table();
            p_by_stratum.Condition = string(strataLevels(:));
            p_by_stratum.nobs = nan(numel(strataLevels), 1);
            p_by_stratum.p_between = nan(numel(strataLevels), 1);

            for i = 1:numel(strataLevels)

                W = T_temp(T_temp.(strataVar) == strataLevels{i}, :);

                W.ID = removecats(categorical(W.ID));
                W.(fixedVar) = removecats(categorical(W.(fixedVar)));

                p_by_stratum.nobs(i) = height(W);

                % Feasibility checks
                if p_by_stratum.nobs(i) < 4 || numel(categories(W.ID)) < 2 || numel(categories(W.(fixedVar))) < 2
                    continue
                end

                try
                    lme = fitlme(W, formula, "FitMethod", "REML");
                    models{i} = lme;

                    % Omnibus between p-value for this stratum
                    a = dataset2table(anova(lme, "DFMethod", "satterthwaite"));
                    rn = string(a.Term);
                    idxB = find(rn == string(fixedVar) | startsWith(rn, string(fixedVar)), 1);
                    if ~isempty(idxB)
                        p_by_stratum.p_between(i) = a.pValue(idxB);
                    end

                    % Pairwise contrasts between levels of betweenVar
                    [L, labels] = contrastsLM(app, lme, fixedVar);

                    pp = nan(size(labels, 1), 1);
                    for k = 1:size(L, 1)
                        pp(k) = coefTest(lme, L(k, :));
                    end

                    qq = mafdr(pp, 'BHFDR', true);

                    mc_tmp = table( ...
                        repmat(string(strataLevels{i}), numel(pp), 1), ...
                        labels(:, 1), labels(:, 2), qq, pp, ...
                        'VariableNames', {'Condition', 'Condition1', 'Condition2', 'pValue', 'pValue_raw'} );

                    tbl = vertcat(tbl, mc_tmp);

                catch ME
                    % optional debug:
                    disp(getReport(ME));
                    models{i} = [];
                end
            end
        end


        %L Helper function for app behavior.
        %
        %   Helper function for app behavior.
        function [L, labels] = contrastsLM(~, mdl, var)
            % Pairwise contrasts of betweenVar level means
            % (covariate-adjusted) using coefTest(mdl, Lrow).

            cn = string(mdl.CoefficientNames);
            lv = string(categories(mdl.Variables.(var)));
            K = numel(lv);

            coefIdx = containers.Map('KeyType', 'char', 'ValueType', 'double');
            coefIdx(char(lv(1))) = 0; % reference level

            for j = 2:K
                pat = string(var) + "_" + lv(j);
                idx = find(cn == pat, 1);

                if isempty(idx)
                    error("Could not find coefficient '%s'. CoefficientNames: %s", pat, strjoin(cn, ", "));
                end

                coefIdx(char(lv(j))) = idx;
            end

            nPairs = K * (K - 1) / 2;
            L = zeros(nPairs, numel(cn));
            labels = strings(nPairs, 2);

            r = 0;
            for a = 1:K-1
                for b = a+1:K
                    r = r + 1;

                    A = lv(a);
                    B = lv(b);

                    iA = coefIdx(char(A));
                    iB = coefIdx(char(B));

                    if iA ~= 0
                        L(r, iA) = L(r, iA) + 1;
                    end
                    if iB ~= 0
                        L(r, iB) = L(r, iB) - 1;
                    end

                    labels(r, :) = [A, B];
                end
            end
        end
    end


%% CALLBACKS


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %STARTUPFCN Initialize app state on startup.
            %
            %   Initialize app state on startup.
            resetVars(app);
            resetFields(app);
            resetComponents(app)
        end

        % Button pushed function: LoaddatasetButton
        function LoaddatasetPushed(app, event)
            %LOADDATASETPUSHED Load a dataset from file and initialize analysis.
            %
            %   Load a dataset from file and initialize analysis.
            [file, path] = uigetfile();
            data = load(fullfile(path, file));
            fields = string(fieldnames(data));
            field = fields(find(structfun(@istable, data), 1, 'first'));
            startupFcn(app)
            app.Indexes = data.(field);
            readDataset(app)
            statisticalTests(app)
            ViewPlots(app)
        end

        % Value changed function: viewWithin
        function viewWithinCheck(app, event)
            %DOWITHINCHECK Toggle within-subject analysis and refresh results.
            %
            %   Toggle within-subject analysis and refresh results.
            app.view_within = app.viewWithin.Value;
            % statisticalTests(app);
            ViewPlots(app)
            disp("do_within changed")
        end

        % Value changed function: viewBetween
        function viewBetweenCheck(app, event)
            %DOBETWEENCHECK Toggle between-subject analysis and refresh results.
            %
            %   Toggle between-subject analysis and refresh results.
            app.view_between = app.viewBetween.Value;
            % statisticalTests(app);
            ViewPlots(app)
            disp("view_betweeen changed")
        end

        % Value changed function: doStratified
        function doStratifiedCheck(app, event)
            %DOSTRATIFIEDCHECK Toggle stratified analysis UI.
            %
            %   Toggle stratified analysis UI.
            app.do_stratified = app.doStratified.Value;
            app.StratifiedVariable.Enable = app.do_stratified;
        end

        % Value changed function: Case, doCompare
        function doCompareCheck(app, event)
            %DOCOMPARECHECK Toggle case comparison mode.
            %
            %   Toggle case comparison mode.
            app.CompareAgainst.Enable = app.doCompare.Value;
            app.caseDiff = app.doCompare.Value;
            if exist('event', 'var')
                areChangesDone(app)
            end
        end

        % Value changed function: FilterbyVariable
        function FilterbyCheck(app, event)
            %FILTERBYCHECK Toggle filtering by variable.
            %
            %   Toggle filtering by variable.
            app.do_filterBy = app.FilterbyVariable.Value;
        end

        % Value changed function: ConsiderCoovariates
        function ConsiderCoovariatesCheck(app, event)
            %CONSIDERCOOVARIATESCHECK Toggle covariate inclusion and refresh.
            %
            %   Toggle covariate inclusion and refresh.
            value = app.ConsiderCoovariates.Value;
            app.do_covariate = value;
            app.CovariatesTree.Enable = value;
            if (~isempty(app.CovariatesTree.CheckedNodes) && value) || ~value
                if exist('event', 'var') && app.do_covariate
                    areChangesDone(app)
                end
            end
        end

        % Value changed function: ResponseVariable
        function ResponseVariableChanged(app, event)
            %RESPONSEVARIABLECHANGED Handle response variable selection.
            %
            %   Handle response variable selection.
            app.anyChanging.responseVar = true;
            app.responseVar = app.ResponseVariable.Value;
            app.responseVar = char(app.responseVar);
            app.anyChanging.responseVar = false;
            if exist('event', 'var')
                areChangesDone(app)
            end
        end

        % Value changed function: BetweenVariable
        function BetweenVariableChanged(app, event)
            %BETWEENVARIABLECHANGED Handle between-variable selection.
            %
            %   Handle between-variable selection.
            app.anyChanging.betweenVar = true;
            app.betweenVar = char(app.BetweenVariable.Value);
            app.WithinVariable.Items = setdiff(app.WithinVars, app.betweenVar);
            if strcmp(app.WithinVariable.Value, app.withinVar) && exist('event', 'var')
                WithinVariableChanged(app)
            end
            app.anyChanging.betweenVar = false;
            if exist('event', 'var') && (app.do_between || app.view_between)
                areChangesDone(app)
            end
        end

        % Value changed function: WithinVariable
        function WithinVariableChanged(app, event)
            %WITHINVARIABLECHANGED Handle within-variable selection.
            %
            %   Handle within-variable selection.
            app.anyChanging.withinVar = true;
            app.withinVar = char(app.WithinVariable.Value);
            if ~isempty(app.SelectedLevels.Children)
                delete(app.SelectedLevels.Children(:));
            end
            for cond = string(app.Conditions.(app.withinVar))'
                uitreenode(app.SelectedLevels, 'text', cond);
            end
            app.SelectedLevelsTree.CheckedNodes = app.SelectedLevels.Children;

            if ~isempty(string(app.CovariateVars))
                app.StratifiedVariable.Items = string(app.CovariateVars);
            else
                app.StratifiedVariable.Items = string([app.WithinVars(:)', app.BetweenVars(:)']);
            end
            var_temp = string(setdiff(app.CovariateVars, app.withinVar));
            temp = app.FilterBy.Value;
            if ~isempty(var_temp)
                app.FilterBy.Items = var_temp;
                if ~strcmp(temp, app.FilterBy.Value)
                    FilterByVariableChanged(app)
                end
            end
            app.BetweenVariable.Items = setdiff(app.BetweenVars, app.withinVar);
            if strcmp(app.BetweenVariable.Value, app.betweenVar)
                BetweenVariableChanged(app)
            end
            if strcmp(app.StratifiedVariable.Value, app.stratifiedVar)
                StratifyVariableChanged(app)
            end


            app.anyChanging.withinVar = false;
            if exist('event', 'var')
                areChangesDone(app)
            end
        end

        % Value changed function: FilterBy
        function FilterByVariableChanged(app, event)
            %FILTERBYVARIABLECHANGED Handle filter variable selection.
            %
            %   Handle filter variable selection.
            app.anyChanging.filterBy = true;
            var_temp = app.FilterBy.Value;
            app.Case.Items = app.Conditions.(var_temp);
            app.CompareAgainst.Items = app.Conditions.(var_temp);
            app.CompareAgainst.Value = app.CompareAgainst.Items(2);
            app.anyChanging.filterBy = false;
            if exist('event', 'var') && app.do_filterBy
                areChangesDone(app)
            end
        end

        % Value changed function: StratifiedVariable
        function StratifyVariableChanged(app, event)
            %STRATIFYVARIABLECHANGED Handle stratification variable selection.
            %
            %   Handle stratification variable selection.
            app.anyChanging.stratifiedVar = true;
            app.stratifiedVar = app.StratifiedVariable.Value;
            app.anyChanging.stratifiedVar = false;
            if exist('event', 'var') && app.do_stratified
                areChangesDone(app)
            end
        end

        % Value changed function: Boxplot, Mean, Median, Violin
        function ViewPlots(app, event)
            %VIEWPLOTS Render plots based on current selections and stats.
            %
            %   Render plots based on current selections and stats.
            if exist('event', 'var') && isprop(event, 'Source') && event.Source.Text == "Violin"
                app.Splitbetweenclasses.Enable = app.Violin.Value;
            end
            if ~app.stats_ok
                app.statisticalTests
            end
            try
                app.FontLine.Value = '1x';
                app.TLayout = tiledlayout(3, 4, 'parent', app.Plots);

                try
                    unit = app.Indexes.Properties.VariableUnits(find(strcmp(app.Indexes.Properties.VariableNames, app.responseVar)));
                catch
                    unit = [];
                end

                if isempty(unit)
                    unit = "";
                end

                midlinemethod = {};
                if app.Mean.Value
                    midlinemethod{end + 1} = 'mean';
                end
                if app.Median.Value
                    midlinemethod{end + 1} = 'median';
                end
                plottype = {};
                if app.Boxplot.Value
                    plottype{end + 1} = 'box';
                end
                if app.Violin.Value
                    plottype{end + 1} = 'violin';
                end
                
                % t = [[],     %   "mixed";
                %      [],     %   "between";
                %      []];    %   "within"];
                
                if app.view_mixed && app.view_between && app.view_within
                    t(1) = nexttile(app.TLayout, 1, [2 ,3]);
                    t(2) = nexttile(app.TLayout, 4, [2, 1]);
                    t(3) = nexttile(app.TLayout, 9, [1, 3]);
                elseif app.view_mixed && app.view_between
                    t(1) = nexttile(app.TLayout, 1, [3, 3]);
                    t(2) = nexttile(app.TLayout, 4, [3, 1]);
                elseif app.view_mixed && app.view_within
                    t(1) = nexttile(app.TLayout, 1, [2, 4]);
                    t(3) = nexttile(app.TLayout, 9, [1, 4]);
                elseif app.view_between && app.view_within
                    t(3) = nexttile(app.TLayout, 1, [3, 3]);
                    t(2) = nexttile(app.TLayout, 4, [3, 1]);
                elseif app.view_mixed
                    t(1) = nexttile(app.TLayout, 1, [3, 4]);
                elseif app.view_between
                    t(2) = nexttile(app.TLayout, 1, [3, 4]);
                elseif app.view_within
                    t(3) = nexttile(app.TLayout, 1, [3, 4]);                    
                end

                
                if app.view_mixed
                    % t(i) = nexttile(app.TLayout, 1, [2 3]);
                    hold(t(1), 'on');
                    boxplotting(app.T, ...
                        'betweenvar', app.betweenVar, ...
                        'x', app.withinVar, ...
                        'y', app.responseVar, ...
                        'title', strcat(app.responseVar, " (Mixed Effect Testing)"), ...
                        'pmixed', app.p_tables.mixed, ...
                        'pstratified', app.p_tables.stratified, ...
                        'isAngle', app.isAngle, ...
                        'axes', t(1), ...
                        'units', unit, ...
                        'midlinemethod', midlinemethod, ...
                        'plottype', plottype);
                end
                if app.view_between
                    hold(t(2), 'on');
                    boxplotting(app.T, ...
                        'x', app.betweenVar, ...
                        'y', app.responseVar, ...
                        'title', strcat(app.responseVar, " (Between Subjects Testing)"), ...
                        'punpaired', app.p_tables.between, ...
                        'isAngle', app.isAngle, ...
                        'axes', t(2), ...
                        'units', unit, ...
                        'midlinemethod', midlinemethod, ...
                        'plottype', plottype);
                end
                if app.view_within
                    if app.split_between
                        if ~ismember(app.betweenVar, app.T.Properties.VariableNames)
                            app.T = innerjoin(app.T, 'keys', app.T.Properties.VariableNames, 'rightvariables', app.betweenVar, 'mergekeys', true);
                        end
                        betweenGroups = numunique(app.T.(app.betweenVar));
                        if (betweenGroups) > 1
                            groupBy = app.betweenVar;
                        end
                    else
                        groupBy = "";
                    end
                    hold(t(3), 'on')
                    boxplotting(app.T, ...
                        'x', app.withinVar, ...
                        'y', app.responseVar, ...
                        'title', strcat(app.responseVar, " (Within Subjects Testing)"), ...
                        'ppaired', app.p_tables.within, ...
                        'groupby', groupBy, ...
                        'isAngle', app.isAngle, ...
                        'axes', t(3), ...
                        'units', unit, ...
                        'midlinemethod', midlinemethod, ...
                        'plottype', plottype);
                    if app.FilterbyVariable.Value
                        title(t(3), app.responseVar + " - " + app.Case.Value);
                    % else
                    %     title(t(3), app.responseVar);
                    end
                end

                if app.view_within  && app.view_mixed
                    linkaxes(t([1, 3]), 'x')
                end
                if sum(arrayfun(@(x) isa(x, 'matlab.graphics.axis.Axes'), t)) > 1
                    linkaxes(t, 'y');
                end
  

            catch ME
                try
                    disp(getReport(ME))
                    % keyboard
                    if ~app.stats_ok
                        statisticalTests(app);
                    end
                catch ME
                    uialert(app.UIFigure, ME, "Error")
                    return
                end
            end

        end

        % Button pushed function: ExportPlotandStatsButton
        function ExportPushed(app, event)
            %EXPORTPUSHED Export plots and statistics to file.
            %
            %   Export plots and statistics to file.
            filename = app.responseVar;
            if app.view_within && app.do_within
                filename = strcat(filename, "_paired_", app.withinVar);
            end
            if app.view_between && app.do_between
                filename = strcat(filename, "_unpaired_", app.betweenVar);
            end
            if app.do_covariate
                filename = strcat(filename, "_covariates_", join(app.covariateVar, '_'));
            end
            [filename, pathname, idx] = uiputfile({'*.svg;*.pdf;*.eps','Vector Image';'*.png;*.jpeg','Rasterized Image'; '*.fig', 'Matlab Figure'}, 'Save as...', filename);
            if isequal(filename,0) || isequal(pathname,0)
                disp('User canceled');
                return;
            end
            figFile = fullfile(pathname, filename);
            % filename = string(extractBefore(filename, '.'));
            pathname = string(pathname);
            switch idx
                case 1 % vector
                    exportgraphics(app.Plots.Children(:), figFile, ...
                        'preserveaspectratio', 'on', ...
                        'backgroundcolor', 'none', ...
                        'contenttype', 'vector')
                case 2 % raster
                    exportgraphics(app.Plots.Children(:), figFile, ...
                        'preserveaspectratio', 'on', ...
                        'backgroundcolor', 'none', ...
                        'resolution', 300, ...
                        'contenttype', 'image')
                case 3 % matlab figure
                    f = figure;
                    newAx = copyobj(app.Plots.Children(:), f);
                    newAx.Position = [0.1300    0.1100    0.7750    0.8150];
                    savefig(f, figFile);
                    close(f)
                otherwise
                    disp('Unrecognized file type');
                    return;
            end
            stats = struct('p_table', app.p_tables,...
                'p_values', app.p_values);
            statsFile =  fullfile(pathname, filename + ".mat");
            save(statsFile, 'stats');
        end

        % Callback function: Physio_SelectpathButton, sociodemo_fileField
        function selectPath(app, event)
            %SELECTPATH Select a folder path for dataset building.
            %
            %   Select a folder path for dataset building.
            app.annotations_path = uigetdir(app.annotations_path);
            app.annotations_pathField = app.annotations_path;
            if ~isempty(app.annotations_path)
                app.Use_anns.Value = true;
            else
                app.Use_anns.Value = false;
            end
        end

        % Button pushed function: Clusters_ChoosefileButton, 
        % ...and 3 other components
        function selectFile(app, event)
            %SELECTFILE Select a data file for dataset building.
            %
            %   Select a data file for dataset building.
            [file, path] = uigetfile({'*.csv', '*.m'});
            app.(strcat('filename_', event.Source.Tag))  = fullfile(path, file);
            app.(strcat(event.Source.Tag, '_fileField')) = fullfile(path, file);
            if ~isempty(fullfile(path, file))
                app.(strcat('Use_', event.Source.Tag)).Value = true;
            else
                app.(strcat('Use_', event.Source.Tag)).Value = false;
            end
        end

        % Button pushed function: BuilddatasetButton_2
        function BuilddatasetPopupPushed(app, event)
            %BUILDDATASETPOPUPPUSHED Build dataset from selected sources.
            %
            %   Build dataset from selected sources.
            d = uiprogressdlg(app.UIFigure, 'message', "building dataset...");
            app.SetpathsPanel.Visible = false;
            resetVars(app)
            educationLevels =  ["Laurea di primo livello", "Bachelor";
                "Laurea magistrale/a ciclo unico", "Master's Degree";
                "Licenza secondaria inferiore", "Middle School";
                "Licenza secondaria superiore", "High School";
                "Post-laurea", "PhD";];

            if ~isempty(app.filename_psycho) && app.Use_psycho.Value
                opts = detectImportOptions(app.filename_psycho, 'sheet', 2);
                opts.VariableNames = arrayfun(@char, unique([app.Variables.SocioDemo, app.Variables.Neighborhood, app.Variables.Scenario, app.Variables.Emotions], 'Stable'), 'UniformOutput', false);
                opts.VariableTypes(ismember(opts.VariableTypes, 'char')) = {'categorical'};
                Psychological = readtable(app.filename_psycho, opts, 'sheet', 2);
                Psychological.POV = categorical(Psychological.POV);

                Psychological.Scenario = renamecats(Psychological.Scenario, {'Future', 'Current'}, {'PA', 'SdF'});
                Psychological(Psychological.EducationLevel == '0', 'EducationLevel') = table(missing);
                Psychological.EducationLevel = removecats(Psychological.EducationLevel, '0');
                Psychological.EducationLevel = renamecats(Psychological.EducationLevel, categories(Psychological.EducationLevel), educationLevels(:, 2)');
            end

            if ~isempty(app.filename_sociodemo) && app.Use_sociodemo.Value
                opts = detectImportOptions(app.filename_sociodemo);
                opts.VariableNames = arrayfun(@char, app.Variables.SocioDemo, 'UniformOutput', false);
                opts.VariableTypes(ismember(opts.VariableTypes, 'char')) = {'categorical'};
                opts.SelectedVariableNames = opts.VariableNames([1:2, 5:end]);
                SocioDemo_temp = readtable(app.filename_sociodemo, opts);
                SocioDemo_temp.EducationLevel = renamecats(SocioDemo_temp.EducationLevel, categories(SocioDemo_temp.EducationLevel), educationLevels(:, 2)');
            end

            if ~isempty(app.filename_clusters) && app.Use_clusters.Value
                opts = detectImportOptions(app.filename_clusters);
                opts.VariableNames = {'ID', 'GSR', 'HR', 'LFHF', 'Cluster'};
                opts.SelectedVariableNames = {'ID', 'Cluster'};
                opts.VariableTypes([1, 5]) = {'categorical'};
                if exist("SocioDemo_temp", "var")
                    SocioDemo_temp = outerjoin(SocioDemo_temp, readtable(app.filename_clusters, opts), 'Keys', 'ID', 'mergekeys', true);
                    app.Variables.SocioDemo(end + 1) = "Cluster";
                    app.Variables.SocioDemo = unique(app.Variables.SocioDemo, 'stable');
                end
            end

            if ~isempty(app.filename_physio) && app.Use_physio.Value
                data = load(app.filename_physio);
                fields = string(fieldnames(data));
                field = fields(find(structfun(@istable, data), 1, 'first'));
                Indexes_temp = data.(field);
                Indexes_temp.SDANN(Indexes_temp.SDANN == 0) = nan;
                Indexes_temp.NumberOfPeaksPerSecond = Indexes_temp.NumberOfPeaks ./ Indexes_temp.DurationSeconds;
                Indexes_temp = renamevars(Indexes_temp, "Phase", "POV");
                Indexes_temp.POV = erase(Indexes_temp.POV, {'_poi', '_cross'});
                for var = string(Indexes_temp.Properties.VariableNames(Indexes_temp.Properties.VariableTypes == "string"))
                    Indexes_temp.(var) = categorical(Indexes_temp.(var));
                end
                Variables_Physio = string(Indexes_temp.Properties.VariableNames(~ismember(Indexes_temp.Properties.VariableTypes, 'categorical')));
                Variables_Physio = Variables_Physio(~contains(Variables_Physio, {'_Std', '_Mean', '_Min', '_Max', 'Index', 'DurationSeconds'}));
                Variables_Physio = Variables_Physio(Variables_Physio ~= "NumberOfPeaks");
                app.Variables.Physio = Variables_Physio;
                if exist("SocioDemo_temp", "var")
                    Indexes_temp = outerjoin(Indexes_temp, SocioDemo_temp, 'keys', 'ID', 'mergekeys', 1, 'type', 'left');
                end
                Indexes_temp.Points = repmat("", size(Indexes_temp.Scenario));
                Indexes_temp.Points(Indexes_temp.Scenario == "Whole") = "Whole Protocol";
                Indexes_temp.Points(Indexes_temp.Scenario == "PA" & Indexes_temp.POV == "whole") = "Entire PA";
                Indexes_temp.Points(Indexes_temp.Scenario == "SdF" & Indexes_temp.POV == "whole") = "Entire SdF";
                Indexes_temp.Points(Indexes_temp.Scenario == "Baseline") = "Baseline";
                Indexes_temp.Points(ismember(string(Indexes_temp.POV), app.pointsOfInterest) & contains(string(Indexes_temp.Label), "start")) = Indexes_temp.POV(ismember(string(Indexes_temp.POV), app.pointsOfInterest) & contains(string(Indexes_temp.Label), "start"));
                if exist("Psychological", "var")
                    Indexes_temp = outerjoin(Indexes_temp, Psychological, 'Keys', intersect(Indexes_temp.Properties.VariableNames, Psychological.Properties.VariableNames), 'type', 'left', 'MergeKeys', true);
                end
                Indexes_temp = Indexes_temp(Indexes_temp.Points ~= "" & ~ismissing(Indexes_temp.Points), :);
                Indexes_temp.Points = categorical(Indexes_temp.Points);
                Indexes_temp = removevars(Indexes_temp, {'DurationSeconds', 'Label'});
            end

            if ~isempty(app.annotations_path) && app.Use_anns.Value && exist("Indexes", "var")
                files = struct2table(dir(app.annotations_path), 'asarray', true);
                files = files(~files.isdir, ["name", "folder"]);
                files.ID = string(erase(files.name, {'Annotations', 'PP', '.mat', '_'}));
                files.folder = string(files.folder);
                Indexes_PP = ["LFHF_Mean", "LFHF_Median", "LFHF_Std", "HF_Mean", "HF_Median", "HF_Std", "LF_Mean", "LF_Median", "LF_Std"];
                for indx = Indexes_PP
                    Indexes_temp.(indx) = NaN(size(Indexes_temp.ID));
                end
                f_resample = 2;
                IDs = unique(Indexes_temp.ID)';
                for id = string(IDs)
                    filePP = fullfile(app.annotations_path, id + "_Annotations_PP.mat");

                    if exist(filePP, 'file')
                        load(filePP, "data")
                        fs = data.fs;
                        idx_ID_in_Indexes = find(Indexes_temp.ID == id);
                        idxs = Indexes_temp.Index(idx_ID_in_Indexes)/fs;
                        idxs(idxs == 0) = 1;
                        idxs = [1; idxs(:); max(structfun(@(x) max(size(x)), data))/fs];
                        t_pp = (1:length(data.pp.Mu)) * data.pp.delta;
                        try
                            for i = ["powLF", "powHF", "LFHF"]

                                if i == "LFHF"
                                    bandPowerTemp = data.BandPower.bal;
                                else
                                    bandPowerTemp = data.BandPower.(i);
                                end
                                for t = 2:length(idxs) - 1
                                    pp_t_temp = t_pp >= idxs(t - 1) & t_pp < idxs(t);
                                    Indexes_temp.(erase(i, "pow") + "_Mean")(idx_ID_in_Indexes(t - 1)) = mean(bandPowerTemp(pp_t_temp), 'omitnan');
                                    Indexes_temp.(erase(i, "pow") + "_Median")(idx_ID_in_Indexes(t - 1)) = median(bandPowerTemp(pp_t_temp), 'omitnan');
                                    Indexes_temp.(erase(i, "pow") + "_Std")(idx_ID_in_Indexes(t - 1)) = std(bandPowerTemp(pp_t_temp), 'omitnan');
                                end
                            end
                        catch ME
                            disp(getReport(ME));
                        end
                    end

                    file = fullfile(app.annotations_path, id + "_Annotations.mat");
                    if exist(file, 'file')
                        data = load(file);
                        try
                            data = data.data;
                        catch ME

                        end
                        try
                            fs = data.fs;
                        catch
                            fs = 256;
                        end
                        idx_ID_in_Indexes = find(Indexes_temp.ID == id);
                        idxs = Indexes_temp.Index(idx_ID_in_Indexes)/fs;
                        idxs(idxs == 0) = 1;
                        idxs = [1; idxs(:); max(structfun(@(x) max(size(x)), data))/fs];
                        try
                            R_series = data.annotations.ecg(1, :)/fs;
                            t = (1:length(data.SCL))/fs;
                            [SCL, t_resample] = resample(data.SCL, t, f_resample);
                            [RR, RR_resample] = resample(diff(R_series), R_series(2:end), f_resample);
                            RR = interp1(RR_resample, RR, t_resample, 'nearest', 'extrap');
                            HR = 60./RR;
                            beta = beta_index(SCL, HR);
                            for i = 2:length(idxs) - 1
                                t_temp = t_resample >= idxs(i - 1) & t_resample < idxs(i);
                                if ~ sum(t_temp)
                                    continue
                                end
                                Indexes_temp.beta_Mean(idx_ID_in_Indexes(i - 1)) = mean(beta(t_temp), 'omitnan');
                                Indexes_temp.beta_Median(idx_ID_in_Indexes(i - 1)) = median(beta(t_temp), 'omitnan');
                                Indexes_temp.beta_Std(idx_ID_in_Indexes(i - 1)) = std(beta(t_temp), 'omitnan');
                                Indexes_temp.SCL_Mean(idx_ID_in_Indexes(i - 1)) = mean(SCL(t_temp), 'omitnan');
                                Indexes_temp.SCL_Median(idx_ID_in_Indexes(i - 1)) = median(SCL(t_temp), 'omitnan');
                                Indexes_temp.SCL_Std(idx_ID_in_Indexes(i - 1)) = std(SCL(t_temp), 'omitnan');
                            end
                        catch ME
                            disp(getReport(ME));
                        end
                    end
                end

                Variables_Physio = unique([Variables_Physio, Indexes_PP,  "beta_Median", "SCL_Median"]);
                app.Indexes_Baseline = Indexes_temp(Indexes_temp.Scenario == "Baseline", ["ID", Variables_Physio]);
            end

            missing_vals = rows2vars(varfun(@(x) sum(ismissing(x)), Indexes_temp));
            missing_vals.OriginalVariableNames = string(erase(missing_vals.OriginalVariableNames, 'Fun_'));
            missing_vals.Properties.RowNames = missing_vals.OriginalVariableNames;
            missing_vals = renamevars(missing_vals, 'Var1', 'NumberOfMissingValues');
            missing_vals.PercentageOfTotal = missing_vals.NumberOfMissingValues / height(Indexes_temp) * 100;
            variablesToKeep = missing_vals.OriginalVariableNames(missing_vals.PercentageOfTotal < 70);
            Indexes_temp = Indexes_temp(:, variablesToKeep);
            app.Indexes = sortrows(Indexes_temp, {'ID', 'Index'});
            app.Variables.Physio = intersect(app.Variables.Physio, variablesToKeep);
            readDataset(app)
            delete(d)

        end

        % Button pushed function: BuilddatasetButton
        function BuilddatasetPushed(app, event)
            %BUILDDATASETPUSHED Open dataset build panel.
            %
            %   Open dataset build panel.
            app.SetpathsPanel.Visible = true;
        end

        % Button pushed function: CancelButton
        function CancelPushed(app, event)
            %CANCELPUSHED Close dataset build panel.
            %
            %   Close dataset build panel.
            app.SetpathsPanel.Visible = false;
        end

        % Value changed function: FontLine
        function FontLineChanged(app, event)
            %FONTLINECHANGED Scale font sizes and line widths in plots.
            %
            %   Scale font sizes and line widths in plots.
            fontMultiplier = str2double(erase(app.FontLine.Value, 'x'));
            oldSize = str2double(erase(event.PreviousValue, 'x'));
            for obj = findobj(app.TLayout, '-property', 'FontSize')'
                obj.FontSize = obj.FontSize / oldSize * fontMultiplier;
            end
            for obj = findobj(app.TLayout, '-property', 'LineWidth')'
                obj.LineWidth = obj.LineWidth / oldSize * fontMultiplier;
            end
        end

        % Button pushed function: ViewBoxchartandStatsButton
        function ViewPlotsPushed(app, event)
            %VIEWPLOTSPUSHED Trigger plot rendering.
            %
            %   Trigger plot rendering.
            ViewPlots(app);
        end

        % Callback function: CovariatesTree
        function CovariatesTreeCheckedNodesChanged(app, event)
            %COVARIATESTREECHECKEDNODESCHANGED Update covariate selection and refresh stats.
            %
            %   Update covariate selection and refresh stats.
            try
                app.covariateVar = {app.CovariatesTree.CheckedNodes(:).Text};
            catch
                app.covariateVar = {};
            end
            statisticalTests(app);
        end

        % Button pushed function: EditVariableTypesButton
        function EditVariableTypesButtonPushed(app, event)
            %EDITVARIABLETYPESBUTTONPUSHED Open variable metadata editor.
            %
            %   Open variable metadata editor.
            appPos = app.UIFigure.Position(1:2);
            appSize = app.UIFigure.Position(3:4);
            boxSize = appSize .* [2/3, 2/5];
            sizeDiff = appSize - boxSize;
            boxPos = appPos + sizeDiff/2;
            app.EditVarsPanel.Position = [boxPos boxSize];
            t = table();
            t(:, 'Variable')        = table(string(app.Indexes.Properties.VariableNames)');
            t(:, 'Description')     = table(string(app.Indexes.Properties.VariableDescriptions)');
            t(:, 'Type')            = table(string(app.Indexes.Properties.VariableTypes)');
            t(:, 'Between')         = table(app.Indexes.Properties.CustomProperties.IsBetween');
            t(:, 'Within')          = table(app.Indexes.Properties.CustomProperties.IsWithin');
            t(:, 'Covariate')       = table(app.Indexes.Properties.CustomProperties.IsCovariate');
            t(:, 'Response')        = table(app.Indexes.Properties.CustomProperties.IsResponse');
            t(:, 'Num. Unique')     = table(varfun(@(x) numunique(x(~ismissing(x))), app.Indexes, "OutputFormat","uniform")');
            t(:, 'Perc. Missing')   = table(varfun(@(x) sum(ismissing(x))/numel(x)*100, app.Indexes, "OutputFormat","uniform")');
            t.Properties.RowNames   = string(app.Indexes.Properties.VariableNames)';
            app.EditVarsTable.Data = t(:, intersect(app.EditVarsTable.ColumnName, t.Properties.VariableNames, 'stable'));
            app.EditVarsPanel.Visible = true;
        end

        % Button pushed function: CancelEdits
        function CancelEditsButtonPushed(app, event)
            %CANCELEDITSBUTTONPUSHED Close variable metadata editor without saving.
            %
            %   Close variable metadata editor without saving.
            app.EditVarsPanel.Visible = false;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            %OKBUTTONPUSHED Apply variable metadata edits and refresh.
            %
            %   Apply variable metadata edits and refresh.
            app.EditVarsPanel.Visible = false;
            t = app.EditVarsTable.Data;
            app.BetweenVars = cellstr(t.Variable(t.Between));
            app.BetweenVariable.Items = app.BetweenVars;
            app.WithinVars = cellstr(t.Variable(t.Within));
            app.WithinVariable.Items = app.WithinVars;
            app.CovariateVars = cellstr(t.Variable(t.Covariate));
            app.FilterBy.Items = app.CovariateVars;
            updateVariableProps(app)

            app.Indexes.Properties.VariableDescriptions = cellstr(t.Description);

            updateCovariateTree(app)

            parseConditions(app)
            app.ResponseVars = cellstr(t.Variable(t.Response));
            app.ResponseVariable.Items = app.ResponseVars;
            getVariables(app)
            statisticalTests(app);
        end

        % Callback function: SelectedLevelsTree
        function SelectedLevelsTreeCheckedNodesChanged(app, event)
            %SELECTEDLEVELSTREECHECKEDNODESCHANGED Update selected levels and refresh stats.
            %
            %   Update selected levels and refresh stats.
            statisticalTests(app);
        end

        % Value changed function: doMixed
        function doMixedValueChanged(app, event)
            app.do_mixed = app.doMixed.Value;
            statisticalTests(app);
            ViewPlots(app)
            disp("do_mixed changed")
        end

        % Value changed function: doBetween
        function doBetweenValueChanged(app, event)
            app.do_between = app.doBetween.Value;
            statisticalTests(app);
            ViewPlots(app)
            disp("do_between changed")
        end

        % Value changed function: doWithin
        function doWithinValueChanged(app, event)
            app.do_within = app.doWithin.Value;
            statisticalTests(app);
            ViewPlots(app)
            disp("do_within changed")            
        end

        % Value changed function: viewMixed
        function viewMixedValueChanged(app, event)
            %VIEWMIXEDCHECK Toggle between-subject analysis and refresh results.
            %
            %   Toggle between-subject analysis and refresh results.
            app.view_mixed = app.viewMixed.Value;
            % statisticalTests(app);
            ViewPlots(app)
            disp("view_mixed changed")
        end

        % Button pushed function: CopyFiguretoClipboardButton
        function CopyFiguretoClipboardButtonPushed(app, event)
            copygraphics(app.Plots.Children(:), 'ContentType', 'vector', 'BackgroundColor', 'none');
        end

        % Context menu opening function: ContextMenu
        function ContextMenuOpening(app, event)
            col = event.InteractionInformation.Column;
            app.ContextMenu.Visible = islogical(app.EditVarsTable.Data(:, col));
        end

        % Menu selected function: DeselectAllMenu, SelectAllMenu
        function SelectAllMenuSelected(app, event)
            % selection = app.EditVarsTable.Selection;
            col = event.InteractionInformation.Column;
            rows = app.EditVarsTable.Selection;
            % for col = unique(selection(:, 2))

            switch event.Source.Text
                case 'Select All'
                    app.EditVarsTable.Data(rows, col).Variables = true(numel(rows), 1);
                case 'Deselect All'
                    app.EditVarsTable.Data(rows, col).Variables = false(numel(rows), 1);
            end
            app.EditVarsTable.Selection = [];
            
            
        end

        % Value changed function: Splitbetweenclasses
        function SplitbetweenclassesValueChanged(app, event)
            app.split_between = app.Splitbetweenclasses.Value;
            
        end

        % Value changed function: SeparateBySubject
        function SeparateBySubjectValueChanged(app, event)
            app.do_bySubject = app.SeparateBySubject.Value;
            statisticalTests(app)
            ViewPlots(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {1100, 1100};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {348, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.PlotOptionsPanel = uipanel(app.GridLayout3);
            app.PlotOptionsPanel.Title = 'Plot Options';
            app.GridLayout7 = uigridlayout(app.PlotOptionsPanel);
            app.TrendLinesLabel = uilabel(app.GridLayout7);
            app.TrendLinesLabel.Text = 'Trend Lines:';
            app.PlottypeLabel = uilabel(app.GridLayout7);
            app.PlottypeLabel.Text = 'Plot type:';
            app.Mean = uicheckbox(app.GridLayout7);
            app.Mean.ValueChangedFcn = createCallbackFcn(app, @ViewPlots, true);
            app.Mean.Text = 'Mean';
            app.Median = uicheckbox(app.GridLayout7);
            app.Median.ValueChangedFcn = createCallbackFcn(app, @ViewPlots, true);
            app.Median.Text = 'Median';
            app.Median.Value = true;
            app.Violin = uicheckbox(app.GridLayout7);
            app.Violin.ValueChangedFcn = createCallbackFcn(app, @ViewPlots, true);
            app.Violin.Text = 'Violin';
            app.Boxplot = uicheckbox(app.GridLayout7);
            app.Boxplot.ValueChangedFcn = createCallbackFcn(app, @ViewPlots, true);
            app.Boxplot.Text = 'Boxplot';
            app.Boxplot.Value = true;
            app.FontSizeandLineWeightsDropDownLabel = uilabel(app.GridLayout7);
            app.FontSizeandLineWeightsDropDownLabel.Text = 'Font Size and Line Weights ';
            app.FontLine = uidropdown(app.GridLayout7);
            app.FontLine.Items = {'1x', '1.5x', '2x', '2.5x', '3x'};
            app.FontLine.ValueChangedFcn = createCallbackFcn(app, @FontLineChanged, true);
            app.FontLine.Value = '1x';
            app.viewWithin = uicheckbox(app.GridLayout7);
            app.viewWithin.ValueChangedFcn = createCallbackFcn(app, @viewWithinCheck, true);
            app.viewWithin.Text = 'Show WithinSubject Testing on plot';
            app.viewBetween = uicheckbox(app.GridLayout7);
            app.viewBetween.ValueChangedFcn = createCallbackFcn(app, @viewBetweenCheck, true);
            app.viewBetween.Text = 'Show Between Subjects Testing on plot';
            app.viewMixed = uicheckbox(app.GridLayout7);
            app.viewMixed.ValueChangedFcn = createCallbackFcn(app, @viewMixedValueChanged, true);
            app.viewMixed.Text = 'Show Mixed Effect Testing on plot';
            app.Splitbetweenclasses = uicheckbox(app.GridLayout7);
            app.Splitbetweenclasses.ValueChangedFcn = createCallbackFcn(app, @SplitbetweenclassesValueChanged, true);
            app.Splitbetweenclasses.Text = 'Split between classes';
            app.Panel = uipanel(app.GridLayout3);
            app.GridLayout8 = uigridlayout(app.Panel);
            app.ExportPlotandStatsButton = uibutton(app.GridLayout8, 'push');
            app.ExportPlotandStatsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPushed, true);
            app.ExportPlotandStatsButton.Text = 'Export Plot and Stats';
            app.ViewBoxchartandStatsButton = uibutton(app.GridLayout8, 'push');
            app.ViewBoxchartandStatsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewPlotsPushed, true);
            app.ViewBoxchartandStatsButton.Text = 'View Boxchart and Stats';
            app.LoaddatasetButton = uibutton(app.GridLayout8, 'push');
            app.LoaddatasetButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddatasetPushed, true);
            app.LoaddatasetButton.Text = 'Load dataset';
            app.BuilddatasetButton = uibutton(app.GridLayout8, 'push');
            app.BuilddatasetButton.ButtonPushedFcn = createCallbackFcn(app, @BuilddatasetPushed, true);
            app.BuilddatasetButton.Visible = 'off';
            app.BuilddatasetButton.Text = 'Build dataset';
            app.EditVariableTypesButton = uibutton(app.GridLayout8, 'push');
            app.EditVariableTypesButton.ButtonPushedFcn = createCallbackFcn(app, @EditVariableTypesButtonPushed, true);
            app.EditVariableTypesButton.Text = 'Edit Variable Types';
            app.CopyFiguretoClipboardButton = uibutton(app.GridLayout8, 'push');
            app.CopyFiguretoClipboardButton.ButtonPushedFcn = createCallbackFcn(app, @CopyFiguretoClipboardButtonPushed, true);
            app.CopyFiguretoClipboardButton.Text = 'Copy Figure to Clipboard';
            app.Panel_2 = uipanel(app.GridLayout3);
            app.GridLayout9 = uigridlayout(app.Panel_2);
            app.MetricLabel = uilabel(app.GridLayout9);
            app.MetricLabel.Text = 'Response Variable:';
            app.ResponseVariable = uidropdown(app.GridLayout9);
            app.ResponseVariable.ValueChangedFcn = createCallbackFcn(app, @ResponseVariableChanged, true);
            app.WithinVariable = uidropdown(app.GridLayout9);
            app.WithinVariable.ValueChangedFcn = createCallbackFcn(app, @WithinVariableChanged, true);
            app.SelectedLevelsTree = uitree(app.GridLayout9, 'checkbox');
            app.SelectedLevels = uitreenode(app.SelectedLevelsTree);
            app.SelectedLevels.Text = 'All';
            % Assign Checked Nodes
            app.SelectedLevelsTree.CheckedNodesChangedFcn = createCallbackFcn(app, @SelectedLevelsTreeCheckedNodesChanged, true);
            app.FilterbyVariable = uicheckbox(app.GridLayout9);
            app.FilterbyVariable.ValueChangedFcn = createCallbackFcn(app, @FilterbyCheck, true);
            app.FilterbyVariable.Text = 'Filter by Variable:';
            app.FilterBy = uidropdown(app.GridLayout9);
            app.FilterBy.ValueChangedFcn = createCallbackFcn(app, @FilterByVariableChanged, true);
            app.Case = uidropdown(app.GridLayout9);
            app.Case.ValueChangedFcn = createCallbackFcn(app, @doCompareCheck, true);
            app.doCompare = uicheckbox(app.GridLayout9);
            app.doCompare.ValueChangedFcn = createCallbackFcn(app, @doCompareCheck, true);
            app.doCompare.Visible = 'off';
            app.doCompare.Enable = 'off';
            app.doCompare.Text = 'Compare against case:';
            app.CompareAgainst = uidropdown(app.GridLayout9);
            app.CompareAgainst.Visible = 'off';
            app.CaseLabel = uilabel(app.GridLayout9);
            app.CaseLabel.Text = 'Case:';
            app.ConsiderCoovariates = uicheckbox(app.GridLayout9);
            app.ConsiderCoovariates.ValueChangedFcn = createCallbackFcn(app, @ConsiderCoovariatesCheck, true);
            app.ConsiderCoovariates.Text = 'Consider Coovariates:';
            app.CovariatesTree = uitree(app.GridLayout9, 'checkbox');
            % Assign Checked Nodes
            app.CovariatesTree.CheckedNodesChangedFcn = createCallbackFcn(app, @CovariatesTreeCheckedNodesChanged, true);
            app.BetweenVariable = uidropdown(app.GridLayout9);
            app.BetweenVariable.ValueChangedFcn = createCallbackFcn(app, @BetweenVariableChanged, true);
            app.SelectedLevelsLabel = uilabel(app.GridLayout9);
            app.SelectedLevelsLabel.Text = 'Selected Levels:';
            app.doStratified = uicheckbox(app.GridLayout9);
            app.doStratified.ValueChangedFcn = createCallbackFcn(app, @doStratifiedCheck, true);
            app.doStratified.Visible = 'off';
            app.doStratified.Enable = 'off';
            app.doStratified.Text = 'Stratify by Variable:';
            app.StratifiedVariable = uidropdown(app.GridLayout9);
            app.StratifiedVariable.ValueChangedFcn = createCallbackFcn(app, @StratifyVariableChanged, true);
            app.StratifiedVariable.Enable = 'off';
            app.StratifiedVariable.Visible = 'off';
            app.WithinVariableLabel = uilabel(app.GridLayout9);
            app.WithinVariableLabel.Text = 'Within Variable:';
            app.BetweenVariableLabel = uilabel(app.GridLayout9);
            app.BetweenVariableLabel.Text = 'Between Variable:';
            app.SeparateBySubject = uicheckbox(app.GridLayout9);
            app.SeparateBySubject.ValueChangedFcn = createCallbackFcn(app, @SeparateBySubjectValueChanged, true);
            app.SeparateBySubject.Text = 'Separate By Subject';
            app.SeparateBySubject.Value = true;
            app.StatisticalTestPanel = uipanel(app.GridLayout3);
            app.StatisticalTestPanel.Title = 'Statistical Test';
            app.GridLayout11 = uigridlayout(app.StatisticalTestPanel);
            app.doWithin = uicheckbox(app.GridLayout11);
            app.doWithin.ValueChangedFcn = createCallbackFcn(app, @doWithinValueChanged, true);
            app.doWithin.Text = 'Paired design (within)';
            app.doBetween = uicheckbox(app.GridLayout11);
            app.doBetween.ValueChangedFcn = createCallbackFcn(app, @doBetweenValueChanged, true);
            app.doBetween.Text = 'Unpaired design (between)';
            app.doMixed = uicheckbox(app.GridLayout11);
            app.doMixed.ValueChangedFcn = createCallbackFcn(app, @doMixedValueChanged, true);
            app.doMixed.Text = 'Mixed design';
            app.withinTest = uilabel(app.GridLayout11);
            app.withinTest.Text = '';
            app.betweenTest = uilabel(app.GridLayout11);
            app.betweenTest.Text = '';
            app.mixedTest = uilabel(app.GridLayout11);
            app.mixedTest.Text = '';
            app.withinReason = uilabel(app.GridLayout11);
            app.withinReason.Text = '';
            app.betweenReason = uilabel(app.GridLayout11);
            app.betweenReason.Text = '';
            app.mixedReason = uilabel(app.GridLayout11);
            app.mixedReason.Text = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.BetweenTab = uitab(app.TabGroup);
            app.BetweenTab.Title = 'Between';
            app.GridLayout6_2 = uigridlayout(app.BetweenTab);
            app.betweenTable = uitable(app.GridLayout6_2);
            app.betweenTable.ColumnName = {'betweenCondition1'; 'betweenCondition2'; 'lowerLim'; 'diff'; 'upperLim'; 'p'};
            app.betweenTable.RowName = {};
            app.betweenTable.ColumnSortable = true;
            app.BetweenWithinTab = uitab(app.TabGroup);
            app.BetweenWithinTab.Title = 'Between-Within';
            app.GridLayout6 = uigridlayout(app.BetweenWithinTab);
            app.mixedTable = uitable(app.GridLayout6);
            app.mixedTable.ColumnName = {'betweenCondition'; 'withinCondition1'; 'withinCondition2'; 'lowerLim'; 'diff'; 'upperLim'; 'p'};
            app.mixedTable.RowName = {};
            app.mixedTable.ColumnSortable = true;
            app.WithinTab = uitab(app.TabGroup);
            app.WithinTab.Title = 'Within';
            app.GridLayout6_3 = uigridlayout(app.WithinTab);
            app.withinTable = uitable(app.GridLayout6_3);
            app.withinTable.ColumnName = {'withinCondition1'; 'withinCondition2'; 'lowerLim'; 'diff'; 'upperLim'; 'p'};
            app.withinTable.RowName = {};
            app.withinTable.ColumnSortable = true;
            app.StratifiedTab = uitab(app.TabGroup);
            app.StratifiedTab.Title = 'Stratified';
            app.GridLayout6_4 = uigridlayout(app.StratifiedTab);
            app.stratifiedTable = uitable(app.GridLayout6_4);
            app.stratifiedTable.ColumnName = {'betweenCondition'; 'withinCondition1'; 'withinCondition2'; 'lowerLim'; 'diff'; 'upperLim'; 'p'};
            app.stratifiedTable.RowName = {};
            app.stratifiedTable.ColumnSortable = true;
            app.Plots = uipanel(app.GridLayout2);
            app.SetpathsPanel = uipanel(app.UIFigure);
            app.SetpathsPanel.Title = 'Set paths';
            app.SetpathsPanel.Visible = 'off';
            app.GridLayout5 = uigridlayout(app.SetpathsPanel);
            app.PhysiologicalIndexesEditFieldLabel = uilabel(app.GridLayout5);
            app.PhysiologicalIndexesEditFieldLabel.Text = 'Physiological Indexes';
            app.physio_fileField = uieditfield(app.GridLayout5, 'text');
            app.Physio_ChoosefileButton = uibutton(app.GridLayout5, 'push');
            app.Physio_ChoosefileButton.ButtonPushedFcn = createCallbackFcn(app, @selectFile, true);
            app.Physio_ChoosefileButton.Text = 'Choose file';
            app.PsychologicalDataEditFieldLabel = uilabel(app.GridLayout5);
            app.PsychologicalDataEditFieldLabel.Text = 'Psychological Data';
            app.psycho_fileField = uieditfield(app.GridLayout5, 'text');
            app.PhysiologicalAnnotationsEditFieldLabel = uilabel(app.GridLayout5);
            app.PhysiologicalAnnotationsEditFieldLabel.Text = 'Physiological Annotations';
            app.annotations_pathField = uieditfield(app.GridLayout5, 'text');
            app.SociodemographicalDataEditFieldLabel = uilabel(app.GridLayout5);
            app.SociodemographicalDataEditFieldLabel.Text = 'Sociodemographical Data:';
            app.sociodemo_fileField = uieditfield(app.GridLayout5, 'text');
            app.sociodemo_fileField.ValueChangedFcn = createCallbackFcn(app, @selectPath, true);
            app.Physio_SelectpathButton = uibutton(app.GridLayout5, 'push');
            app.Physio_SelectpathButton.ButtonPushedFcn = createCallbackFcn(app, @selectPath, true);
            app.Physio_SelectpathButton.Tag = 'physio';
            app.Physio_SelectpathButton.Text = 'Select path';
            app.Psycho_ChoosefileButton = uibutton(app.GridLayout5, 'push');
            app.Psycho_ChoosefileButton.ButtonPushedFcn = createCallbackFcn(app, @selectFile, true);
            app.Psycho_ChoosefileButton.Tag = 'psycho';
            app.Psycho_ChoosefileButton.Text = 'Choose file';
            app.Sociodemo_ChoosefileButton = uibutton(app.GridLayout5, 'push');
            app.Sociodemo_ChoosefileButton.ButtonPushedFcn = createCallbackFcn(app, @selectFile, true);
            app.Sociodemo_ChoosefileButton.Tag = 'sociodemo';
            app.Sociodemo_ChoosefileButton.Text = 'Choose file';
            app.BuilddatasetButton_2 = uibutton(app.GridLayout5, 'push');
            app.BuilddatasetButton_2.ButtonPushedFcn = createCallbackFcn(app, @BuilddatasetPopupPushed, true);
            app.BuilddatasetButton_2.Text = 'Build dataset';
            app.Use_physio = uicheckbox(app.GridLayout5);
            app.Use_physio.Text = '';
            app.Use_anns = uicheckbox(app.GridLayout5);
            app.Use_anns.Text = '';
            app.Use_psycho = uicheckbox(app.GridLayout5);
            app.Use_psycho.Text = '';
            app.Use_sociodemo = uicheckbox(app.GridLayout5);
            app.Use_sociodemo.Text = '';
            app.ClusteringDataLabel = uilabel(app.GridLayout5);
            app.ClusteringDataLabel.Text = 'Clustering Data';
            app.clusters_fileField = uieditfield(app.GridLayout5, 'text');
            app.Clusters_ChoosefileButton = uibutton(app.GridLayout5, 'push');
            app.Clusters_ChoosefileButton.ButtonPushedFcn = createCallbackFcn(app, @selectFile, true);
            app.Clusters_ChoosefileButton.Tag = 'clusters';
            app.Clusters_ChoosefileButton.Text = 'Choose file';
            app.Use_clusters = uicheckbox(app.GridLayout5);
            app.Use_clusters.Text = '';
            app.CancelButton = uibutton(app.GridLayout5, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.EditVarsPanel = uipanel(app.UIFigure);
            app.EditVarsPanel.Title = 'Edit Variables';
            app.EditVarsPanel.Visible = 'off';
            app.GridLayout10 = uigridlayout(app.EditVarsPanel);
            app.EditVarsTable = uitable(app.GridLayout10);
            app.EditVarsTable.ColumnName = {'Variable'; 'Description'; 'Type'; 'Between'; 'Within'; 'Covariate'; 'Response'; 'Num. Unique'; 'Perc. Missing'};
            app.EditVarsTable.ColumnRearrangeable = 'on';
            app.EditVarsTable.RowName = {};
            app.EditVarsTable.ColumnSortable = true;
            app.EditVarsTable.SelectionType = 'row';
            app.EditVarsTable.ColumnEditable = [false true false true true true true false false];
            app.OKButton = uibutton(app.GridLayout10, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelEdits = uibutton(app.GridLayout10, 'push');
            app.CancelEdits.ButtonPushedFcn = createCallbackFcn(app, @CancelEditsButtonPushed, true);
            app.CancelEdits.Text = 'Cancel';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenuOpening, true);
            app.SelectAllMenu = uimenu(app.ContextMenu);
            app.SelectAllMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectAllMenuSelected, true);
            app.SelectAllMenu.Text = 'Select All';
            app.DeselectAllMenu = uimenu(app.ContextMenu);
            app.DeselectAllMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectAllMenuSelected, true);
            app.DeselectAllMenu.Text = 'Deselect All';
            % Assign app.ContextMenu
            app.EditVarsTable.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StatsView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end